classdef cellNavGuide_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        manageLibrary                  matlab.ui.container.Menu
        RefreshcelldataMenu            matlab.ui.container.Menu
        menuImport                     matlab.ui.container.Menu
        CelldatafetchwindowMenu        matlab.ui.container.Menu
        menuTransformVolume            matlab.ui.container.Menu
        menu_importSWC                 matlab.ui.container.Menu
        menueAnalyze                   matlab.ui.container.Menu
        CellAnalysisSkeletonMenu       matlab.ui.container.Menu
        runEditSkeleton                matlab.ui.container.Menu
        menuGraphPointToPoint          matlab.ui.container.Menu
        menuCellTable                  matlab.ui.container.Menu
        menuDataPanel                  matlab.ui.container.Menu
        menu_FindAppositions           matlab.ui.container.Menu
        menuCustomize                  matlab.ui.container.Menu
        menuObjectGroups               matlab.ui.container.Menu
        menuCustomGroups               matlab.ui.container.Menu
        customSynGroup                 matlab.ui.container.Menu
        menuAnnotate                   matlab.ui.container.Menu
        menuePointList                 matlab.ui.container.Menu
        menuMakePath                   matlab.ui.container.Menu
        RunScriptsMenu                 matlab.ui.container.Menu
        ProjectsMenu                   matlab.ui.container.Menu
        DisplayMenu                    matlab.ui.container.Menu
        ClassificationMenu             matlab.ui.container.Menu
        PublicationMenu                matlab.ui.container.Menu
        ClearPanel                     matlab.ui.container.Panel
        AllButton                      matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        SynapsesTab                    matlab.ui.container.Tab
        text_synSynapseType_3          matlab.ui.control.Label
        popupmenu_postClassSynapse     matlab.ui.control.DropDown
        text_synSynapseType_2          matlab.ui.control.Label
        popupmenu_preClassSynapse      matlab.ui.control.DropDown
        togglebutton_synVisible        matlab.ui.control.StateButton
        edit_synPostGroup              matlab.ui.control.EditField
        edit_synPreGroup               matlab.ui.control.EditField
        popupmenu_synPostGroup         matlab.ui.control.DropDown
        popupmenu_synPreGroup          matlab.ui.control.DropDown
        pushbutton25                   matlab.ui.control.Button
        pushbutton24                   matlab.ui.control.Button
        pushbutton_synDelete           matlab.ui.control.Button
        popupmenu_synGroup             matlab.ui.control.DropDown
        pushbutton_synMakeGroup        matlab.ui.control.Button
        popupmenu_synMarkerAlpha       matlab.ui.control.DropDown
        popupmenu_synMarkerColor       matlab.ui.control.DropDown
        text_synMarker                 matlab.ui.control.Label
        popupmenu_synMarker            matlab.ui.control.DropDown
        text_synSynapseType            matlab.ui.control.Label
        text_postText                  matlab.ui.control.Label
        text_preText                   matlab.ui.control.Label
        popupmenu_synSynapseType       matlab.ui.control.DropDown
        LabelsTab                      matlab.ui.container.Tab
        RegistertoViewAngleButton      matlab.ui.control.Button
        ClickPosButton                 matlab.ui.control.StateButton
        scaleBar_DimDropDown           matlab.ui.control.DropDown
        DimDropDownLabel               matlab.ui.control.Label
        scaleBar_WidthEditField        matlab.ui.control.NumericEditField
        WidthLabel                     matlab.ui.control.Label
        scaleBar_HeightEditField       matlab.ui.control.NumericEditField
        HeightEditFieldLabel           matlab.ui.control.Label
        scaleBar_ScaleBarButton        matlab.ui.control.StateButton
        scaleBar_YSlider               matlab.ui.control.Slider
        YSliderLabel                   matlab.ui.control.Label
        scaleBar_ZSlider               matlab.ui.control.Slider
        ZSliderLabel                   matlab.ui.control.Label
        scaleBar_XSlider               matlab.ui.control.Slider
        XSliderLabel                   matlab.ui.control.Label
        SavePicTab                     matlab.ui.container.Tab
        PDFvectorCheckBox              matlab.ui.control.CheckBox
        PNGfastsmallCheckBox           matlab.ui.control.CheckBox
        CropEmptyEdgesCheckBox         matlab.ui.control.CheckBox
        InvertPalletCheckBox           matlab.ui.control.CheckBox
        pushbutton_loadGlob            matlab.ui.control.Button
        pushbutton_saveGlob            matlab.ui.control.Button
        pushbutton_runRender           matlab.ui.control.Button
        popupmenu_renderFunctions      matlab.ui.control.DropDown
        pushbutton_snapShot            matlab.ui.control.Button
        text_FileName                  matlab.ui.control.Label
        edit_saveDirectory             matlab.ui.control.EditField
        pushbutton_SelectDir           matlab.ui.control.Button
        edit13                         matlab.ui.control.EditField
        DrawpathTab                    matlab.ui.container.Tab
        togglebutton_hidePath          matlab.ui.control.StateButton
        pushbutton_clearPath           matlab.ui.control.Button
        pushbutton_undoPath            matlab.ui.control.Button
        togglebutton_measurePath       matlab.ui.control.StateButton
        edit_pathPanel_ConvexHullArea  matlab.ui.control.EditField
        text_ConvexHullArea            matlab.ui.control.Label
        edit_pathPanelVolume           matlab.ui.control.EditField
        text_pathPanelVolume           matlab.ui.control.Label
        edit_pathPanelXYArea           matlab.ui.control.EditField
        text_pathPanelXYArea           matlab.ui.control.Label
        edit5                          matlab.ui.control.EditField
        text_pathPanelTotLength        matlab.ui.control.Label
        edit_pathPanelLengths          matlab.ui.control.TextArea
        text_pathPanelLengths          matlab.ui.control.Label
        edit_pathPanelPoints           matlab.ui.control.TextArea
        text_pathPanel_points          matlab.ui.control.Label
        EditpatchTab                   matlab.ui.container.Tab
        togglebutton_SelectPatch       matlab.ui.control.StateButton
        togglebutton_PaintPatch        matlab.ui.control.StateButton
        text_alpha                     matlab.ui.control.Label
        edit_alpha                     matlab.ui.control.EditField
        text_color                     matlab.ui.control.Label
        edit_color                     matlab.ui.control.EditField
        ConsoleTab                     matlab.ui.container.Tab
        edit_result                    matlab.ui.control.TextArea
        pushbutton_eval                matlab.ui.control.Button
        edit_eval                      matlab.ui.control.TextArea
        pushbutton_Function            matlab.ui.control.Button
        text_functionType              matlab.ui.control.Label
        popupmenu_functions            matlab.ui.control.DropDown
        popupmenu_funcTypes            matlab.ui.control.DropDown
        HistogramsTab                  matlab.ui.container.Tab
        toggle_refDat                  matlab.ui.control.StateButton
        toggle_groupData2              matlab.ui.control.StateButton
        toggle_groupData1              matlab.ui.control.StateButton
        toggle_CellData                matlab.ui.control.StateButton
        axesDepth                      matlab.ui.control.UIAxes
        InteractivityPanel             matlab.ui.container.Panel
        ZoomToggle                     matlab.ui.control.StateButton
        RotateToggle                   matlab.ui.control.StateButton
        PanToggle                      matlab.ui.control.StateButton
        uipanel19                      matlab.ui.container.Panel
        popupmenu_sourceVolume         matlab.ui.control.DropDown
        uipanel_textOut                matlab.ui.container.Panel
        textOut                        matlab.ui.control.TextArea
        uipanel_SelectedCell           matlab.ui.container.Panel
        togglebutton_pickRef           matlab.ui.control.StateButton
        textCID                        matlab.ui.control.StateButton
        toggle_selectCell              matlab.ui.control.StateButton
        textRefCell                    matlab.ui.control.StateButton
        edit2                          matlab.ui.control.EditField
        edit1                          matlab.ui.control.EditField
        prevCell                       matlab.ui.control.Button
        text8                          matlab.ui.control.Label
        subType_popupMenue             matlab.ui.control.DropDown
        textCellType                   matlab.ui.control.Label
        nextCell                       matlab.ui.control.Button
        listCIDs                       matlab.ui.control.ListBox
        popupCellType                  matlab.ui.control.DropDown
        panelGroup                     matlab.ui.container.Panel
        togglebutton_showGroup         matlab.ui.control.StateButton
        togglebutton_cell2group        matlab.ui.control.StateButton
        subAlpha_popUp                 matlab.ui.control.DropDown
        deleteGroup                    matlab.ui.control.StateButton
        addRef_toggleButton            matlab.ui.control.StateButton
        renderGroups_popUp             matlab.ui.control.DropDown
        subColor_popUp                 matlab.ui.control.DropDown
        showSub_toggle                 matlab.ui.control.StateButton
        panelGetPos                    matlab.ui.container.Panel
        toggle_highlightCell           matlab.ui.control.StateButton
        pushDataTip                    matlab.ui.control.StateButton
        panelConnections               matlab.ui.container.Panel
        pushbutton_makeTable           matlab.ui.control.Button
        toggleSkeleton                 matlab.ui.control.StateButton
        toggleRadius                   matlab.ui.control.StateButton
        pushGrowMarker                 matlab.ui.control.Button
        pushShrinkMarker               matlab.ui.control.Button
        pushPostSyn                    matlab.ui.control.StateButton
        pushPreSyn                     matlab.ui.control.StateButton
        pushbutton_postGroup           matlab.ui.control.Button
        pushbutton_preGroup            matlab.ui.control.Button
        listbox3                       matlab.ui.control.DropDown
        listbox_preCol                 matlab.ui.control.DropDown
        pushPostCells                  matlab.ui.control.StateButton
        pushPreCell                    matlab.ui.control.StateButton
        panelView                      matlab.ui.container.Panel
        SetOriginButton                matlab.ui.control.StateButton
        ClearFieldButton               matlab.ui.control.Button
        text_showRef                   matlab.ui.control.Label
        pushbutton_blendPatches        matlab.ui.control.Button
        pushbutton_resetZoom           matlab.ui.control.StateButton
        raiseLight                     matlab.ui.control.Button
        viewYZ                         matlab.ui.control.Button
        viewXZ                         matlab.ui.control.Button
        viewXY                         matlab.ui.control.Button
        popupmenu_OtherObjects         matlab.ui.control.DropDown
        mainAx                         matlab.ui.control.UIAxes
    end


    methods (Access = private)
        function blendPatches(app)

            global glob

            pch = findobj(glob.handles.mainAx,'type','patch');

            for i = 1:length(pch)
                tag(i) = str2num(pch(i).Tag);
            end

            uTags = unique(tag);
            for i = 1:length(uTags)
                isTag = find(tag==uTags(i));
                if length(isTag)>1
                    cols = cat(1,pch(isTag).FaceColor);
                    alphs = cat(1,pch(isTag).FaceAlpha);
                    alphs = alphs/sum(alphs);
                    cols = cols.*repmat(alphs,[1,3]);
                    newCol = sum(cols,1);
                    for p = 1:length(isTag)
                        pch(isTag(p)).FaceColor = newCol;
                    end
                end
            end
        end

        function cellCon(app)

            global glob tis

            targ = glob.pickCID;
            isSyn = find(tis.syn.post == targ);
            isPre = unique(tis.syn.pre(isSyn));
            isPre = setdiff(isPre,0);

            isSyn = find(tis.syn.pre == targ);
            isPost = unique(tis.syn.post(isSyn));
            isPost = setdiff(isPost,0);

            glob.con.isPre = isPre;
            glob.con.isPost = isPost;
        end

        function txt = cursorPaintPatch(app, ~, info)

            global glob
            'cursor Select'
            set(glob.dcm,'Enable','off')

            glob.editPatch.h = info.Target;

            if ~isempty(glob.editPatch.col)
                set(info.Target,'FaceColor',glob.editPatch.col)
            end
            if ~isempty(glob.editPatch.alph)
                set(info.Target,'FaceAlpha',glob.editPatch.alph)
            end


            pause(.1)
            set(glob.dcm,'Enable','on')
        end

        function txt = cursorSelectCell(app, ~, info)

            global glob
            'cursor Select'
            tag = get(info.Target,'Tag');
            set(glob.handles.textOut,'String',['tag = ' tag]);
            txt = tag;
            clipboard('copy',tag);

            cid = str2num(tag);
            idx = find(glob.cids==cid,1);
            if cid ~= glob.pickCID
                if isempty(idx)
                    set(glob.handles.textOut,'String','cell(cid) not found');
                else
                    glob.pickIdx = idx;
                    glob.pickCID = cid;
                    showPickedCell(app)
                end
            end
            pause(.01)
        end

        function txt = cursorSelectPatch(app, ~, info)

            global glob
            'cursor Select'
            set(glob.dcm,'Enable','off')
            tag = get(info.Target,'Tag');
            set(glob.handles.textOut,'String',['tag = ' tag]);
            txt = tag;
            clipboard('copy',tag);


            col = get(info.Target,'FaceColor');
            alph = get(info.Target,'FaceAlpha');

            glob.editPatch.col = round(col*100)/100;
            glob.editPatch.alph = round(alph*100)/100;
            glob.editPatch.h = info.Target;

            set(glob.handles.edit_color,'String',...
                num2str(glob.editPatch.col,'%0.2f  '));
            set(glob.handles.edit_alpha,'String',...
                num2str(glob.editPatch.alph,'%0.2f  '));

            pause(.1)
            set(glob.dcm,'Enable','on')
        end

        function txt = cursorSelectRef(app, ~, info)

            global glob
            'cursor Select'
            set(glob.dcm,'Enable','off')
            tag = get(info.Target,'Tag');
            set(glob.handles.textOut,'String',['tag = ' tag]);
            txt = tag;
            clipboard('copy',tag);

            cid = str2num(tag);
            idx = find(glob.cids==cid,1);

            if sum(glob.pickCIDref==cid) %If alread a ref
                glob.pickCIDref = setdiff(glob.pickCIDref, cid);
            else

                if isempty(idx)
                    set(glob.handles.textOut,'String','cell(cid) not found');
                else
                    glob.pickCIDref = [glob.pickCIDref cid];
                end
            end

            str = {num2str(glob.pickCIDref)};
            set(glob.handles.edit2,'String',str);
            edit2_function(app);
            pause(.2)

            set(glob.dcm,'Enable','on')
        end

        function deselectToggleUI(app, handles, hObject)

            tag = get(hObject,'Tag');
            hList = {'togglebutton_SelectPatch','togglebutton_PaintPatch',...
                'togglebutton_measurePath','pushDataTip','toggle_highlightCell',...
                'toggle_selectCell','togglebutton_pickRef'};

            for i = 1:length(hList)
                if ~strcmp(tag,hList{i})
                    eval(sprintf('app.%s.Value = 0;',hList{i}))
                    %%%!!! appdesigner conversion error
                    %eval([hList{i} '_Callback(handles.' hList{i} ',[],handles)']);
                end
            end
        end

        function txt = displayCoordinates(app, ~, info)
            global glob tis
            X = info.Position(1);
            Y = info.Position(2);
            Z = info.Position(3);
            %c_info = getCursorInfo(glob.dcm)
            pos = [Y X Z]

            %%Remove shiftZ
            if isfield(glob.vol,'shiftZ')
                dsPos = pos / tis.obI.em.dsRes;
                zInd = round(dsPos(:,3));
                zInd(zInd<1) = 1;
                shiftZ = glob.vol.shiftZ.shifts;
                shiftZ = shiftZ * tis.obI.em.dsRes;
                if size(shiftZ,1)>=zInd
                    xshift = shiftZ(zInd,1);
                    yshift = shiftZ(zInd,2);
                    pos(:,1) = pos(:,1) - xshift;
                    pos(:,2) = pos(:,2) - yshift;
                end
            end

            anc2sub = (glob.em.res/ 1000);
            umPos = pos
            umPosStr = sprintf('%.0f  %.0f  %.0f',umPos(end,2),umPos(end,1),umPos(end,3));
            disp(umPos)


            VASTpos = round(pos ./ anc2sub);
            VASTpos = VASTpos([2 1 3]);
            VASTposStr = sprintf('%.0f \r%.0f \r%.0f um',umPos(end,2),umPos(end,1),umPos(end,3));

            set(glob.handles.textOut,'String',VASTpos);
            tag = get(info.Target,'Tag');

            txt = umPosStr;

            clipboard('copy',VASTpos)
        end

        % function drawSyn(app)
        %
        %     global glob tis
        %
        %     L = app.popupmenu_synGroup.Value;
        %     strs = app.popupmenu_synGroup.Items;
        %
        %     synfo = glob.syn.g(L);
        %     synfo.name = strs{L};
        %
        %     try
        %         delete(synfo.p);
        %     end
        %
        %     try (isempty(synfo.preName));
        %
        %     end
        %
        %     if isempty(synfo.preName) & isempty(synfo.postName)
        %
        %         pos = synfo.pos;
        %         synText = synfo.name;
        %     else
        %
        %         uPreCid = tis.cids(synfo.preCellIdx);
        %         uPostCid = tis.cids(synfo.postCellIdx);
        %
        %         if isempty(synfo.preName) | strcmp(synfo.preName,'none')
        %             uPreCid = [0  tis.cids];
        %         end
        %         if isempty(synfo.postName)| strcmp(synfo.postName,'none')
        %             uPostCid = [0  tis.cids];
        %         end
        %
        %
        %         pre = tis.syn.pre;
        %         post = tis.syn.post;
        %
        %         %     [ix preSynID ib] = intersect(pre,uPreCid);
        %         %     [ix postSynID ib] = intersect(post,uPostCid);
        %         postSynID = [];
        %         for i = 1:length(uPostCid);
        %             postSynID = [postSynID; find(post==uPostCid(i))];
        %         end
        %         preSynID = [];
        %         for i = 1:length(uPreCid);
        %             preSynID = [preSynID; find(pre==uPreCid(i))];
        %         end
        %
        %         %     if isempty(uPreCid) & ~isempty(uPostCid)
        %         %         isSyn = postSynID;
        %         %     elseif ~isempty(uPreCid) & isempty(uPostCid)
        %         %         isSyn = preSynID;
        %         %     else
        %         %         isSyn = intersect(postSynID,preSynID);
        %         %     end
        %         isSyn = intersect(postSynID,preSynID);
        %
        %         %%Filter for synapse Type
        %         synType = str2num(synfo.synType);
        %         if ~isempty(synType)
        %             isType = find(tis.syn.synType == synType);
        %             isSyn = intersect(isSyn,isType);
        %         end
        %         if synfo.preTypeID>0
        %             isPreType = find(tis.syn.preClass == synfo.preTypeID);
        %             isSyn = intersect(isSyn,isPreType);
        %         end
        %         if synfo.postTypeID>0
        %             isPostType = find(tis.syn.postClass == synfo.postTypeID);
        %             isSyn = intersect(isSyn,isPostType);
        %         end
        %
        %
        %
        %         % pos = tis.syn.synPosDS(isSyn,[2 1 3]) * glob.em.dsRes(1);
        %         pos = tis.syn.pos(isSyn,[2 1 3]);
        %
        %
        %         %%Translate positions
        %         if isfield(glob.vol,'shiftZ')
        %         dsPos = pos / tis.obI.em.dsRes;
        %         zInd = ceil(dsPos(:,3));
        %         zInd(zInd<1) = 1;
        %         shiftZ = glob.vol.shiftZ.shifts;
        %         shiftZ = shiftZ * tis.obI.em.dsRes;
        %         maxPos = max(zInd);
        %         if maxPos>length(shiftZ)
        %             shiftZ(maxPos,:) = [0 0];
        %         end
        %         yshift = shiftZ(zInd,2);
        %         xshift = shiftZ(zInd,1);
        %         pos(:,1) = pos(:,1) + yshift;
        %         pos(:,2) = pos(:,2) + xshift;
        %         end
        %
        %
        %         synfo.pos = pos;
        %         synfo.synIdx = isSyn;
        %
        %         preStr =  get(glob.handles.edit_synPreGroup,'String');
        %         postStr = get(glob.handles.edit_synPostGroup,'String');
        %
        %         if isempty(preStr),preStr = 'all';end
        %         if isempty(postStr),postStr = 'all';end
        %         synfo.name = sprintf('[ %s --> %s ] synType = %s',preStr, postStr, synfo.synType);
        %         synText = num2str([pre(isSyn) post(isSyn)]);
        %     end
        %
        %     set(glob.handles.mainAx, 'NextPlot', 'add');
        %     if ~isempty(pos)
        %         synfo.p = scatter3(glob.ax,pos(:,1),pos(:,2),pos(:,3),synfo.markerSize,...
        %             'markerfacecolor',synfo.col,'markerfacealpha',synfo.alph,...
        %             'marker',synfo.markerType,'markeredgecolor','w',...
        %             'MarkerEdgeAlpha',synfo.alph );
        %     end
        %
        %     try
        %         set(synfo.p,'clipping','off');
        %     end
        %
        %     gStrs = app.popupmenu_synGroup.Items;
        %
        %     gStrs{L} = synfo.name;
        %     app.popupmenu_synGroup.Items = gStrs;
        %     app.popupmenu_synGroup.ItemsData = 1:length(gStrs);
        %
        %     app.popupmenu_synGroup.Value = L ;
        %
        %     showText(app, synText);
        %     glob.syn.g(L) = synfo;
        % end

        function edit_synPost_Callback(app, hObject, eventdata, handles)
            % hObject    handle to edit_synPost (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit_synPost as text
            %        str2double(get(hObject,'String')) returns contents of edit_synPost as a double
            global glob

            str = get(hObject,'string');
            if iscell(str)
                str = str{1};
            end
            cid = str2num(str);
            idx = find(glob.cids==cid,1);

            if isempty(idx)
                set(handles.textOut,'String','cell(cid) not found');
            else


                glob.syn.postPickIdx = idx;
                glob.syn.postPickCID = cid;


            end
        end

        function edit_synPost_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_synPost (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function edit_synPre_Callback(app, hObject, eventdata, handles)
            % hObject    handle to edit_synPre (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit_synPre as text
            %        str2double(get(hObject,'String')) returns contents of edit_synPre as a double
            global glob

            str = get(hObject,'string');
            if iscell(str)
                str = str{1};
            end
            cid = str2num(str);
            idx = find(glob.cids==cid,1);

            if isempty(idx)
                set(handles.textOut,'String','cell(cid) not found');
            else


                glob.syn.prePickIdx = idx;
                glob.syn.prePickCID = cid;

            end
        end

        function edit_synPre_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_synPre (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function col = getCol(app, colOption, L)

            if ~exist('L','var')
                L = 1;
            end
            if colOption == 2
                col = hsv(L);
                col = col(randperm(L),:);
            elseif colOption == 1
                col = [rand rand rand];
                col = repmat(col,[L 1]);
            else
                colIdx = colOption - 2;
                colVals = [1 0 0; 0 1 0; 0 0 1; 1 1 0; 1 0 1; 0 1 1; 1 1 1; .3 .3 .3];
                colPick = colVals(colIdx,:);
                col = repmat(colPick,[L 1]);
            end
        end

        function txt = highlightCell(app, ~, info)
            global glob
            'highlighting'
            try
                set(glob.highlight.oldPatch,...
                    'FaceColor', glob.highlight.oldColor,...
                    'FaceAlpha',glob.highlight.oldAlpha )
            end
            glob.highlight.oldPatch = info.Target;
            glob.highlight.oldAlpha = get(info.Target,'FaceAlpha');
            glob.highlight.oldColor =  get(info.Target,'FaceColor');

            tag = get(info.Target,'Tag');
            set(glob.handles.textOut,'String',['tag = ' tag]);

            txt = tag;
            set(info.Target,'FaceAlpha',1,'FaceColor',[1 1 .5])


            clipboard('copy',tag)
        end

        function txt = makePath(app, ~, info)
            global glob
            X = info.Position(2);
            Y = info.Position(1);
            Z = info.Position(3);
            %c_info = getCursorInfo(glob.dcm)

            newPt = [Y X Z];

            if ~isempty(glob.data.path.pts)
                lastPt = glob.data.path.pts(end,:);
            else
                lastPt = [0 0 0];
            end

            if sum(abs(newPt-lastPt))

                glob.data.path.pts = cat(1,glob.data.path.pts,[Y X Z]);
                glob.data.path.Parent = info.Target.Parent;

                anc2sub = (glob.em.res/ 1000)./ glob.em.dsRes;
                allPos2 = [X Y Z] ./ anc2sub;
                VASTpos = sprintf('%.0f  %.0f  %.0f',allPos2(end,2),allPos2(end,1),allPos2(end,3));
                disp(VASTpos)

                anc2um =  1./ glob.em.dsRes;
                umPos = [X Y Z];
                umPosStr = sprintf('%.2f %.2f %.2f um',umPos(end,2),umPos(end,1),umPos(end,3));

                tag = get(info.Target,'Tag');


                glob.data.path.pos = cat(1,glob.data.path.pos,umPos);

                pos = glob.data.path.pos;

                L = sqrt((pos(2:end,1)-pos(1:end-1,1)).^2 + (pos(2:end,2)-pos(1:end-1,2)).^2 + ...
                    (pos(2:end,3)-pos(1:end-1,3)).^2);
                glob.data.path.lengths = L;
                txt = sprintf('%0.2f um',sum(L));

                clipboard('copy',sum(L))

                plotPath(app)
            end
        end

        function Mstr = mat2strFormat(app, M, n)

            maxL = length(num2str(max(round(M(:)))))+n+3;
            ys = size(M,1);
            xs = size(M,2) * maxL-2;
            Mstr(ys,xs) = ' ';
            for y = 1:size(M,1)
                for x = 1:size(M,2)
                    str1 = sprintf('%%0.%df',n);
                    str2 = sprintf(str1,M(y,x));
                    Mstr(y,maxL*x-length(str2)+1-2:maxL*x-2) = str2;
                end
            end
        end

        function plotPath(app)

            global glob

            pos = glob.data.path.pos;
            pts = glob.data.path.pts;
            textOutStr = num2str(round(pos));

            set(glob.handles.textOut,'String',textOutStr);

            try
                delete(glob.data.path.plotH);
            end

            if ~isempty(pos)
                set(glob.data.path.Parent, 'NextPlot', 'add')

                glob.data.path.plotH = plot3(glob.data.path.Parent,...
                    pts(:,1),pts(:,2),pts(:,3),...
                    'linewidth',10,'color','g');
                set(glob.data.path.Parent,'Clipping','Off')
            end

            set(glob.data.path.plotH,'Visible',...
                ~get(glob.handles.togglebutton_hidePath,'Value'));

            if get(glob.handles.togglebutton_pathProperties,'Value')
                updatePathPanel(app)
            end
        end

        function popupmenu8_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function popupmenu_selectWindows_Callback(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu_selectWindows (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_selectWindows contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_selectWindows

            strs = app.popupmenu_selectWindows.Items
            val = app.popupmenu_selectWindows.Value;
            str = strs{val};

            switch str
                case 'Import Data'
                    glob.windows.importData = ImportData;
                case 'Create Custom Object'
                    glob.windows.customObject = customObject;
                case 'Copy cellNav'
                    glob.windows.copyCellNav = copyCellNav;
                case 'Transform Volume'
                    glob.windows.transformVolume = transformVolume;
                case 'Generate point to point graphs'
                    glob.windows.pt2ptGraph = GraphPointToPoint;
                case 'Cell Table'
                    glob.windows.cellTable = cellTable;
                case 'Translate List'
                    glob.windows.translateList = translateList;
                case 'Create Custom Group'
                    glob.windows.customGroup = customGroup;
                otherwise
                    set(handles.textOut,'String','Window not registered')
            end
        end

        function popupmenu_selectWindows_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu_selectWindows (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end

            str = {'Import Data','Generate point to point graphs','Translate List','Create Custom Object', 'Create Custom Group',...
                'Model Transmission','Transform Volume','Copy cellNav','Cell Table'};
            set(hObject,'String',str);
        end

        function popupmenu_synCell_Callback(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu_synCell (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_synCell contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_synCell

            global glob tis

            val = app.popupmenu_synCell.Value;
            glob.syn.prePickIdx = glob.syn.prelistCellidx(val);
            glob.syn.prePickCID = tis.cids(glob.syn.prePickIdx );
            set(handles.edit_synPre, 'String',num2str(glob.syn.prePickCID))
        end

        function popupmenu_synCell_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu_synCell (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function popupmenu_synPostCell_Callback(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu_synPostCell (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_synPostCell contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_synPostCell

            global glob tis

            val = app.popupmenu_synPostCell.Value;
            glob.syn.postPickIdx = glob.syn.postlistCellidx(val);
            glob.syn.postPickCID = tis.cids(glob.syn.postPickIdx );
            set(handles.edit_synPost, 'String',num2str(glob.syn.postPickCID))

        end

        function popupmenu_synPostCell_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu_synPostCell (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function popupmenu_synPostSubType_Callback(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu_synPostSubType (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_synPostSubType contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_synPostSubType
            global glob tis


            val = app.popupmenu_synPostSubType.Value;

            glob.syn.postSubTypeID = val-1;

            if val == 1;
                glob.syn.postlistCellidx = find(tis.cells.type.typeID == glob.syn.preTypeID);
            else
                glob.syn.postlistCellidx = find((tis.cells.type.typeID == glob.syn.preTypeID) & ...
                    (tis.cells.type.subTypeID == glob.syn.postSubTypeID) );

            end

            % set(handles.popupmenu_synPostCell,'String',glob.cellStr(glob.syn.postlistCellidx) )
            app.popupmenu_synPostCell.Items = glob.cellStr(glob.syn.postlistCellidx);
            pickCids = tis.cids(glob.syn.postlistCellidx);
            cellText = sprintf('selected cells  = %s ',num2str(pickCids,'%d '))
            showText(app, cellText);
        end

        function popupmenu_synPostSubType_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu_synPostSubType (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function popupmenu_synPostType_Callback(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu_synPostType (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_synPostType contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_synPostType

            global glob tis


            val = app.popupmenu_synPostType.Value;
            if val == 1
                glob.syn.postlistCellidx = 1:glob.cellNum;
                glob.syn.postSubTypeID = 0;
            else
                glob.syn.postTypeID =glob.typeIDs(val);
                glob.syn.postlistCellidx = find(tis.cells.type.typeID == glob.syn.postTypeID );
                glob.syn.postSubTypeID = 0;
            end

            set(handles.popupmenu_synPostCell,'String',glob.cellStr(glob.syn.postlistCellidx))
            glob.syn.postPickIdx = 1;
            glob.syn.postPickCID = tis.cids(glob.syn.postPickIdx);

            if glob.syn.postTypeID < 1
                subTypeNames = {'all'};
            else
                subTypeNames = tis.cells.type.subTypeNames{glob.syn.postTypeID };
                subTypeNames = cat(2,{'all'},subTypeNames);
            end

            set(handles.popupmenu_synPostSubType,'String',subTypeNames)
            set(handles.popupmenu_synPostSubType,'Value',1)
        end

        function popupmenu_synPostType_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu_synPostType (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end

            global glob tis

            set(hObject,'String',glob.typeStrings);
        end

        function popupmenu_synPreSubType_Callback(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu_synPreSubType (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_synPreSubType contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_synPreSubType
            global glob tis


            val = app.popupmenu_synPreSubType.Value;

            glob.syn.preSubTypeID = val-1;

            if val == 1;
                glob.syn.prelistCellidx = find(tis.cells.type.typeID == glob.syn.preTypeID );
            else
                glob.syn.prelistCellidx = find((tis.cells.type.typeID == glob.syn.preTypeID ) & ...
                    (tis.cells.type.subTypeID == glob.syn.preSubTypeID ) );

            end

            set(handles.popupmenu_synCell,'String',glob.cellStr(glob.syn.prelistCellidx) )
            pickCids = tis.cids(glob.syn.prelistCellidx);
            cellText = sprintf('selected cells  = %s ',num2str(pickCids,'%d '))
            showText(app, cellText);
        end

        function popupmenu_synPreSubType_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu_synPreSubType (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function popupmenu_synPreType_Callback(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu_synPreType (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_synPreType contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_synPreType

            global glob tis


            val = app.popupmenu_synPreType.Value;

            if val == 1
                glob.syn.prelistCellidx = 1:glob.cellNum;
                glob.syn.preSubTypeID = 0;
            else
                glob.syn.preTypeID =glob.typeIDs(val);
                glob.syn.prelistCellidx = find(tis.cells.type.typeID == glob.syn.preTypeID);
                glob.syn.preSubTypeID = 0;
            end

            set(handles.popupmenu_synCell,'String',glob.cellStr(glob.syn.prelistCellidx))
            glob.syn.prePickIdx = 1;
            glob.syn.prePickCID = tis.cids(glob.syn.prePickIdx);

            if glob.typeID < 1
                subTypeNames = {'all'};
            else
                subTypeNames = tis.cells.type.subTypeNames{glob.syn.preTypeID};
                subTypeNames = cat(2,{'all'},subTypeNames);
            end

            set(handles.popupmenu_synPreSubType,'String',subTypeNames)
            set(handles.popupmenu_synPreSubType,'Value',1)

            drawSyn(app)
        end

        function popupmenu_synPreType_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu_synPreType (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end

            global glob tis

            set(hObject,'String',glob.typeStrings);
        end


        function showPickedCell(app)

            global glob tis
            disp('Showing selected cell')
            set(glob.handles.edit1,'String',num2str(glob.pickCID))

            %%%!!! App designer conversion error
            %             pushPostSyn_Callback(glob.handles.pushPostSyn,[],glob.handles);
            %             pushPreSyn_Callback(glob.handles.pushPreSyn,[],glob.handles);
            %             pushPostCells_Callback(glob.handles.pushPostCells,[],glob.handles);
            %             pushPreCell_Callback(glob.handles.pushPreCell,[],glob.handles);

            fileName = sprintf('%s%d.mat',glob.useFvDir,glob.pickCID);
            fv = loadFV(fileName);;
            %     set(handles.mainAx)
            %     patch(handles.mainAx,fv);

            try
                delete(glob.p.cell)
            end

            volName = glob.vol.activeName;
            if exist([glob.dir.Volumes volName '\volTransform.mat'],'file');
                load([glob.dir.Volumes volName '\volTransform.mat']);
            else
                volTransform = [];
            end

            glob.p.cell = renderFVnav(fv,[1 1 1],1,glob.pickCID,volTransform);
            %axis off
            pause(.01)


            %%%!!! Appdeveloper conversion error
            %             toggleSkeleton_Callback(glob.handles.toggleSkeleton);
            %             toggle_CellData_Callback(glob.handles.toggle_CellData,[],glob.handles) %activate show data for cell
            %


            set(glob.handles.textCID ,'Value',1)
        end

        function showText(app, textStr)
            global glob
            set(glob.handles.textOut,'String',textStr );
        end

        function synGroup2Panel(app)

            global glob
            val = app.popupmenu_synGroup.Value;
            %             set(glob.handles.popupmenu_synMarker,'Value',glob.syn.g(val).markerTypeIdx);
            %             set(glob.handles.popupmenu_synMarkerColor,'Value',glob.syn.g(val).colIdx);
            %             set(glob.handles.popupmenu_synMarkerAlpha,'Value',glob.syn.g(val).alphIdx);
            %             set(glob.handles.popupmenu_synPreGroup,'Value',1)
            %             set(glob.handles.popupmenu_synPostGroup,'Value',1)
            %             set(glob.handles.edit_synPreGroup,'String',glob.syn.g(val).preName);
            %             set(glob.handles.edit_synPostGroup,'String',glob.syn.g(val).postName);
            %             set(glob.handles.togglebutton_synVisible,'Value',glob.syn.g(val).show);
            %
            app.popupmenu_synMarker.Value = glob.syn.g(val).markerTypeIdx;
            app.popupmenu_synMarkerColor.Value = glob.syn.g(val).colIdx;
            app.popupmenu_synMarkerAlpha.Value = glob.syn.g(val).alphIdx;
            app.popupmenu_synPreGroup.Value = 1;
            app.popupmenu_synPostGroup.Value = 1;
            app.popupmenu_synSynapseType.Value = glob.syn.g(val).synTypeListIdx;
            set(glob.handles.edit_synPreGroup,'String',glob.syn.g(val).preName);
            set(glob.handles.edit_synPostGroup,'String',glob.syn.g(val).postName);
            set(glob.handles.togglebutton_synVisible,'Value',glob.syn.g(val).show);


        end

        function toggleBipolars_Callback(app, hObject, eventdata, handles)
            % hObject    handle to toggleBipolars (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of toggleBipolars

            global glob tis

            val = get(hObject,'Value')


            try
                isHand = isvalid(glob.p.pBipolars);
            catch err
                isHand = 0;
            end

            if val
                if isHand
                    glob.p.pBipolars.Visible = 'on';
                else
                    bipCids = glob.cids(tis.cells.type.typeID == 7);
                    vert = [];
                    fac = [];
                    for i = 1:length(bipCids)
                        fileName = sprintf('%s%d.mat',glob.useFvDir,bipCids(i));
                        fv = loadFV(fileName);
                        fac = cat(1,fac,fv.faces+size(vert,1));
                        vert = cat(1,vert,fv.vertices);
                    end

                    fv.vertices = vert;
                    fv.faces = fac;
                    glob.p.pBipolars = renderFVnav(fv,[0 .8 0],.1);

                end
            else
                if isHand
                    glob.p.pBipolars.Visible = 'off';
                end
            end
        end

        function toggleBorders_Callback(app, hObject, eventdata, handles)
            % hObject    handle to toggleBorders (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of toggleBorders

            global glob

            val = get(hObject,'Value');
            try
                isHand = isvalid(glob.p.pRefINL);
            catch err
                isHand = 0;
            end

            if val
                if isHand
                    glob.p.pRefGCL.Visible = 'on';
                    glob.p.pRefINL.Visible = 'on';
                else
                    load([glob.useFvDir 'ref_gcl nucEdge.mat'])
                    glob.p.pRefGCL = renderFVnav(fv,[1 1 0],.5)

                    load([glob.useFvDir 'ref_inl nucEdge.mat'])
                    glob.p.pRefINL = renderFVnav(fv,[1 0 1],.5)
                end
            else
                if isHand
                    glob.p.pRefGCL.Visible = 'off'
                    glob.p.pRefINL.Visible = 'off'
                end

            end
        end

        function toggleScaleBar_Callback(app, hObject, eventdata, handles)
            % hObject    handle to toggleScaleBar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of toggleScaleBar
            global glob

            val = get(hObject,'Value')
            try
                isHand = isvalid(glob.p.pRefMicronBar);
            catch err
                isHand = 0;
            end

            if val
                if isHand
                    glob.p.pRefMicronBar.Visible = 'on';
                    glob.p.pRefMicronPt1.Visible = 'on';
                    glob.p.pRefMicronPt2.Visible = 'on';
                else
                    load([glob.useFvDir 'ref_10 micron bar.mat'])
                    glob.p.pRefMicronBar = renderFVnav(fv,[1 1 1],1)

                    load([glob.useFvDir 'ref_10 micron point 1.mat'])
                    glob.p.pRefMicronPt1 = renderFVnav(fv,[1 1 1],.5)

                    load([glob.useFvDir 'ref_10 micron point 2.mat'])
                    glob.p.pRefMicronPt2 = renderFVnav(fv,[1 1 1],.5)
                end
            else
                if isHand
                    glob.p.pRefMicronBar.Visible = 'off';
                    glob.p.pRefMicronPt1.Visible = 'off';
                    glob.p.pRefMicronPt2.Visible = 'off';
                end
            end
        end

        function togglebutton_ShowPathPanel_Callback(app, hObject, eventdata, handles)
            % hObject    handle to togglebutton_ShowPathPanel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_ShowPathPanel


            val = get(hObject,'Value');
            if val
                set(handles.uipanel_pathProperties ,'Visible','On')
            else
                set(handles.uipanel_pathProperties , 'Visible','Off')
            end
        end


        function updatePathPanel(app)

            global glob

            h = glob.handles;


            pos = glob.data.path.pos;

            posStr = num2str(pos,'%0.1f  ');
            %posStr2 = mat2strFormat(glob.data.path.pos,1);
            set(h.edit_pathPanelPoints,'String',posStr)

            lengthsStr = num2str(glob.data.path.lengths,'%0.1f  ');
            set(h.edit_pathPanelLengths,'String',lengthsStr)

            lengthStr = compose('%0.1f',sum(glob.data.path.lengths));
            set(h.edit5,'String',lengthStr)

            area = 0;
            convexArea = 0;
            convexVolume = 0;

            if ~isempty(pos)
                area = polyarea(pos(:,1),pos(:,2));
            end
            if size(pos,1)>2
                [hull2D convexArea] = convhull(pos(:,1),pos(:,2));
            end
            if size(pos,1)>3
                [hull3D convexVolume] = convhull(pos(:,1),pos(:,2),pos(:,3));
            end

            areaStr = compose('%0.1f',area);
            set(h.edit_pathPanelXYArea,'String',areaStr)

            convAreaStr = compose('%0.1f',convexArea);
            set(h.edit_pathPanel_ConvexHullArea,'String',convAreaStr)

            volStr = compose('%0.1f',sum(glob.data.path.lengths));
            set(h.edit_pathPanelVolume,'String',volStr)
        end

        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)

            % Set component properties that require runtime configuration
            %       app.uitoggletool1.ClickedCallback = 'putdowntext(''zoomin'',gcbo)';
            %       app.uitoggletool2.ClickedCallback = 'putdowntext(''pan'',gcbo)';
            %       app.uitoggletool3.ClickedCallback = 'putdowntext(''rotate3d'',gcbo)';
            %       app.uitoggletool4.ClickedCallback = 'insertmenufcn(gcbf,''Colorbar'')';
            %       app.uitoggletool6.ClickedCallback = 'insertmenufcn(gcbf,''Legend'')';
            %             app.uipushtool1.ClickedCallback = 'filemenufcn(gcbf,''FileSave'')';
            %             app.uipushtool3.ClickedCallback = 'filemenufcn(gcbf,''FileNew'')';
            %             app.uipushtool4.ClickedCallback = 'printdlg(gcbf)';
            %             app.uipushtool5.ClickedCallback = 'filemenufcn(gcbf,''FileOpen'')';
            app.listCIDs.scroll(app.listCIDs.Items{1});
        end

        function  functionToggelCellData(app)
            global glob tisDat

            cid = glob.pickCID
            cellIdx = glob.pickIdx;
            cellDat = tisDat.depthHist(cellIdx);
            depth = cellDat.histDat(:,1);
            surface = cellDat.histDat(:,2);
            surface = surface/max(surface);

            glob.dat.h.cell = plot(app.axesDepth,surface,depth,...
                'Color',[0 0 0],'linewidth',2);
            set(app.axesDepth,'YLim',[0 1],'XLim',[-.5 1.5],'ydir','normal',...
                'YTick',[-.5:.1:1.5])
        end

        function results = func46(app)

        end

        function txt = setOrigin(app, ~, info)

            global glob
            X = info.Position(1);%!dim
            Y = info.Position(2);
            Z = info.Position(3);
            %c_info = getCursorInfo(glob.dcm)

            [a b] = view;
            yl = get(app.mainAx,'YLim');
            xl = get(app.mainAx,'XLim');
            zl = get(app.mainAx,'ZLim');
            yr = diff(yl)/2;
            xr = diff(xl)/2;
            zr = diff(zl)/2;
            set(app.mainAx,'XLim',[X-xr X+xr],...
                'YLim',[Y-yr Y+yr],'ZLim',[Z-zr Z+zr]);
            view([a b]);
            txt = 'origin'
        end

        function results = floatingFigure(app)
            global glob
            glob.float.f = figure('Name','CellNav Display','NumberTitle','off');
            glob.float.ax = gca;

            z = zoom(glob.float.f);
            setAxes3DPanAndZoomStyle(z,glob.float.ax,'limits')
            glob.float.ax.Clipping = 0;

            [a e ] = view;
            glob.light = lightangle(a+45,e + 45);

            view([0 0])
            glob.float.ax.Color = [0 0 0];
            glob.float.ax.Visible = 'off';
            glob.float.f.Color = [0 0 0];

            % Create mainAx
            glob.float.ax.CameraViewAngle = 6.60861036031192;
            glob.float.ax.DataAspectRatio = [1 1 1];
            glob.float.ax.PlotBoxAspectRatio = [1 1 1];
            glob.float.ax.XLim = [107.822037795516 178.543639760456];
            glob.float.ax.YLim = [112.350262826547 183.071864791487];
            glob.float.ax.ZLim = [-20.0603557517311 50.6612462132089];
            glob.float.ax.Color = [0 0 0];
            glob.float.ax.XGrid = 'off';
            glob.float.ax.YGrid = 'off';
            glob.float.ax.ZGrid = 'off';
            glob.float.ax.FontSize = 13.3333333333333;
            glob.float.ax.NextPlot = 'add';
            glob.float.ax.Visible = 'on';
            glob.float.ax.Tag = 'floatingFig';
            %glob.float.ax.Position = [-2 22 1127 796];
            axis off

            glob.ax = glob.float.ax;
            glob.fig = glob.float.f;
            %             tbf = uitoolbar(glob.fig,'Visible','off')
            glob.tb = axtoolbar(glob.ax,{'rotate' 'pan' 'zoomin' 'zoomout'  'restoreview' 'export'})
            %             'panInteraction','rotateInteraction',...
            %                 'zoomInteraction','regionZoomInteraction','rulerPanInteraction');
            %
        end

        function  popupCellType_Function(app)
            global glob tis

            strs = app.popupCellType.Items; % get current options
            val = app.popupCellType.Value; % get current selection
            typeNames = tis.cells.type.typeNames; % Get potential options in tissue
            typeID = find(strcmp(tis.cells.type.typeNames,strs{val}),1); %Find option in tissue

            %%Select cell IDs
            if strcmp(strs{val},'all') % if all
                glob.listCellidx = 1:length(tis.cids);
                glob.subTypeID = 0;
            elseif strcmp(strs{val},'none') | strcmp(strs{val},'unassigned')
                glob.listCellidx = find(tis.cells.type.typeID ==0);
            else
                glob.typeID = typeID;
                glob.listCellidx = find(tis.cells.type.typeID == typeID);
                glob.subTypeID = 0;
            end

            %Fill in boxes with items
            app.listCIDs.Items = glob.cellStr(glob.listCellidx);
            app.listCIDs.ItemsData = 1:length(glob.cellStr(glob.listCellidx));
            app.listCIDs.Value = 1;
            glob.pickIdx = 1;
            glob.pickCID = tis.cids(glob.pickIdx);         
            
            %%Find subtype names
            if glob.typeID < 1
                subTypeNames = {'all'};
            else
                subTypeNames = tis.cells.type.subTypeNames{glob.typeID};
                subTypeNames = cat(2,{'all' 'none'},subTypeNames);
            end

            app.subType_popupMenue.Items = subTypeNames;
            app.subType_popupMenue.ItemsData = 1:length(subTypeNames);
            app.subType_popupMenue.Value = 1;
        end

        function results = flashTextOut(app)
            for i = 1:5
                app.textOut.BackgroundColor = [1 0 0];
                pause(.1)
                app.textOut.BackgroundColor = [1 1 1];
                pause(.1)
            end
        end

        function [fileName, GID]  = parseSheetAddress(app,datStr)

            if iscell(datStr)
                datStr = datStr{1};
            end
            slash = regexp(datStr,'/');
            fileName = datStr(slash(end-1)+1:slash(end)-1);
            gidE  = regexp(datStr,'gid=');
            GID = datStr(gidE(end)+4:end);
        end

        function results = subType_popupMenue_Function(app)

            global glob tis

            val = app.subType_popupMenue.Value;
            strs = app.subType_popupMenue.Items;

            if glob.typeID
                subNames = tis.cells.type.subTypeNames{glob.typeID};
                targID = find(strcmp(subNames,strs{val}),1);

                glob.subTypeID = targID;

                if strcmp(strs{val}, 'all');
                    glob.listCellidx = find(tis.cells.type.typeID == glob.typeID);
                elseif strcmp(strs{val}, 'none')
                    glob.listCellidx = find((tis.cells.type.typeID == glob.typeID) & ...
                        (tis.cells.type.subTypeID == 0) );
                else
                    glob.listCellidx = find((tis.cells.type.typeID == glob.typeID) & ...
                        (tis.cells.type.subTypeID == glob.subTypeID) );
                end


                app.listCIDs.Items = glob.cellStr(glob.listCellidx);
                app.listCIDs.ItemsData = 1:length(glob.cellStr(glob.listCellidx));

                pickCids = tis.cids(glob.listCellidx);
                cellText = sprintf('selected cells  = %s ',num2str(pickCids,'%d '))
                showText(app, cellText);
            end
        end

        function results = drawScaleBar(app)

            global glob


            try glob.p.scaleBar.delete
            end

            y = app.scaleBar_YSlider.Value;
            x = app.scaleBar_XSlider.Value;
            z = app.scaleBar_ZSlider.Value;
            w = app.scaleBar_WidthEditField.Value;
            h = app.scaleBar_HeightEditField.Value;
            dim = app.scaleBar_DimDropDown.Value;

            %%make corners
            y2 = y+h;
            x2 = x+h;
            z2 = z+h;
            if strcmp(dim,'Y')
                y2 = y+w;
            elseif strcmp(dim,'X')
                x2 = x+w;
            else
                z2 = z+w;
            end


            %%transform
            [az el] = view(glob.ax)

            ym = (y+y2)/2;
            xm = (x+x2)/2;
            zm = (z+z2)/2;
            vert = [y x z; y2 x z; y2 x2 z; y x2 z; ...
                y x z2; y2 x z2; y2 x2 z2; y x2 z2];
            vert = vert - repmat([ym xm zm],[size(vert,1) 1]);

            T = viewmtx(az,0);
            switchDim = [1 3 2]; %works for side view

            vert4d = [vert(:,switchDim) ones(size(vert,1),1)]';
            vert = T*vert4d;
            vert = vert(switchDim,:)';
            vert = vert + repmat([ym xm zm],[size(vert,1) 1]);


            %% make verticies
            sb.vertices = vert;
            sb.faces = [1 2 3 4; 5 6 7 8; 1 2 6 5; 3 4 8 7; 2 3 7 6; 1 4 8 5];
            glob.p.scaleBar = patch(glob.ax,sb,'facecolor','w','edgecolor','w','linestyle','none',...
                'SpecularStrength',0,'DiffuseStrength',1);


        end

        function txt = scaleBar_click(app,~, info)

            global glob
            X = info.Position(2);
            Y = info.Position(1);
            Z = info.Position(3);
            %c_info = getCursorInfo(glob.dcm)

            umPos = [X Y Z] ;
            umPosStr = sprintf('%.0f  %.0f  %.0f',umPos(end,2),umPos(end,1),umPos(end,3));
            disp(umPos)


            app.scaleBar_YSlider.Value = Y;
            app.scaleBar_XSlider.Value = X;
            app.scaleBar_ZSlider.Value = Z;
            drawScaleBar(app)
            txt = umPosStr;

            clipboard('copy',umPos)
        end

        function results = func57(app)

        end
    end

    methods (Access = public)

        function showCellGroup(app, V)
            global glob tis

            if ~exist('V','var')
                V = 1:length(glob.g);
            end

            for v = V

                val = tis.cells.cids(glob.g(v).idx);

                if glob.g(v).show

                    try
                        for r = 1:length(glob.g(v).patch)
                            delete(glob.g(v).patch(r))
                        end
                    end

                    volName = glob.vol.activeName;
                    if exist([glob.dir.Volumes volName '\volTransform.mat'],'file');
                        load([glob.dir.Volumes volName '\volTransform.mat']);
                    else
                        volTransform = [];
                    end

                    for i = 1:length(val)
                        fileName = sprintf('%s%d.mat',glob.useFvDir,val(i));
                        fv = loadFV(fileName);
                        glob.g(v).patch(i) = renderFVnav(fv,glob.g(v).col(i,:),glob.g(v).alph,val(i),volTransform);
                        pause(.01)
                    end
                else
                    glob.g(v).patch = [];
                end

            end
        end


        function updateGroups(app)


            %newVal = max(min(G,length(groupNames)),1);
            global glob
            groupNames ={glob.g.name};
            if length(groupNames)<1;
                groupNames = {' '};
            end
            app.renderGroups_popUp.Items =groupNames;
            app.renderGroups_popUp.ItemsData =1:length(groupNames);

            app.renderGroups_popUp.Value =length(groupNames);
            app.popupmenu_synPreGroup.Items=groupNames;
            app.popupmenu_synPreGroup.ItemsData = [1:length(groupNames)];
            %app.popupmenu_synPreGroup.Value = groupNames(end);
            app.popupmenu_synPostGroup.Items=groupNames;
            app.popupmenu_synPostGroup.ItemsData = [1:length(groupNames)];
            %app.popupmenu_synPostGroup.Value = groupNames(end);

            if length(glob.g)
                L = get(glob.handles.renderGroups_popUp,'Value');
                set(glob.handles.subColor_popUp,'Value',glob.g(L).colIdx);
                set(glob.handles.subAlpha_popUp,'Value',glob.g(L).alphIdx);
                set(glob.handles.togglebutton_showGroup,'Value',glob.g(L).show);
            end
        end


        function drawObj(app,dsObj,obI,obIds,v)

            global glob

            flipDim = [1 3 2 ];
            downSamp = 1;
            renderProps.smooth = 0;
            renderProps.resize = 1;
            renderProps.smoothPatch = 0;

            volName = glob.vol.activeName;
            if exist([glob.dir.Volumes volName '\volTransform.mat'],'file');
                load([glob.dir.Volumes volName '\volTransform.mat']);
            else
                volTransform = [];
            end


            %% Draw cells
            for i = 1:length(obIds)
                sub = dsObj(obIds(i)).subs;
                if ~isempty(sub)
                    smallSub = shrinkSub(sub,downSamp);
                    smallSub = smallSub(:,flipDim);
                else
                    smallSub = [];
                end

                if isempty(smallSub)
                    fv.vertices = [];
                    fv.faces = [];
                else
                    fv = subVolFV(smallSub,[],renderProps);
                end

                %% Scale
                fv.vertices = fv.vertices * obI.em.dsRes(1);
                set(0,'CurrentFigure',glob.handles.figure1)

                
                glob.g(v).patch(i) = renderFVnav(fv,glob.g(v).col(i,:),...
                    glob.g(v).alph,glob.g(v).cid{i},volTransform);

            end

        end


        function takeSnapShot(app)

            global glob

            pause(.01)
            saveDir = app.edit_saveDirectory.Value
            imageName = [saveDir glob.save.fileName];
            for i = 1:1000
                snp = sprintf('_snp%d.png',i);
                if ~exist([imageName snp],'file')
                    break
                end
            end

            snPDF = [snp(1:end-3) '.pdf'];

            app.edit13.Value = ['writing ...' glob.save.fileName snp];
            app.textOut.Value = ['writing ' imageName snp];
            pause(.01)
            fig1 = glob.handles.figure1;
            fig2 = figure('visible','on');

            fig2.Color = fig1.Color;
            % fig2.Position = fig1.Position;
            % fig2.OuterPosition = fig1.OuterPosition;
            apos = app.mainAx.Position
            apos = glob.fig.Position


            fig2.Position = apos;%[  1   1   1536   1024];

            newax = copyobj(glob.ax,fig2);

            set(fig2,'PaperUnits','points','PaperPosition', apos);
            set(fig2, 'InvertHardCopy', 'off');

            if app.PDFvectorCheckBox.Value
                print(fig2,[imageName snPDF],'-dpdf','-painters');
            end

            if app.PNGfastsmallCheckBox.Value
                print(fig2,[imageName snp],'-dpng','-r256','-opengl','-noui');
                pause(.03)

                shouldProcess = app.CropEmptyEdgesCheckBox.Value | app.InvertPalletCheckBox.Value
                if shouldProcess
                    I = imread([imageName snp]);

                    if app.CropEmptyEdgesCheckBox.Value
                        [y x] = find(sum(I,3)>0);
                        minY = max(1,min(y)-10);
                        minX = max(1,min(x)-10);
                        maxY = min(size(I,1),max(y)+10);
                        maxX = min(size(I,2),max(x)+10);
                        I = I(minY:maxY,minX:maxX,:);
                    end
                    if app.InvertPalletCheckBox.Value
                        I = 255-I;
                    end
                    clf(fig2,'reset')
                    ax = axes(fig2);
                    image(ax,I);
                    pause(1)
                    imwrite(I,[imageName snp]);
                end
                pause(.1)
                close(fig2)
                app.edit13.Value = glob.save.fileName;
            end
        end

        function clearAllGroups(app)
            global glob

            for G = 1:length(glob.g)

                try
                    for p = 1:length(glob.g(G).patch)
                        delete(glob.g(G).patch(p))
                    end
                end
            end
            glob.g = glob.defaultG;
            updateGroups(app)
        end

        function drawSyn(app)

            global glob tis

            L = app.popupmenu_synGroup.Value;
            strs = app.popupmenu_synGroup.Items;

            synfo = glob.syn.g(L);
            synfo.name = strs{L};

            try
                delete(synfo.p);
            end

            try (isempty(synfo.preName));

            end

            if isempty(synfo.preName) & isempty(synfo.postName)

                pos = synfo.pos;
                synText = synfo.name;
            else

                uPreCid = tis.cids(synfo.preCellIdx);
                uPostCid = tis.cids(synfo.postCellIdx);

                if isempty(synfo.preName) | strcmp(synfo.preName,'none')
                    uPreCid = [0  tis.cids];
                end
                if isempty(synfo.postName)| strcmp(synfo.postName,'none')
                    uPostCid = [0  tis.cids];
                end


                pre = tis.syn.pre;
                post = tis.syn.post;

                %     [ix preSynID ib] = intersect(pre,uPreCid);
                %     [ix postSynID ib] = intersect(post,uPostCid);
                postSynID = [];
                for i = 1:length(uPostCid);
                    postSynID = [postSynID; find(post==uPostCid(i))];
                end
                preSynID = [];
                for i = 1:length(uPreCid);
                    preSynID = [preSynID; find(pre==uPreCid(i))];
                end

                %     if isempty(uPreCid) & ~isempty(uPostCid)
                %         isSyn = postSynID;
                %     elseif ~isempty(uPreCid) & isempty(uPostCid)
                %         isSyn = preSynID;
                %     else
                %         isSyn = intersect(postSynID,preSynID);
                %     end
                isSyn = intersect(postSynID,preSynID);

                %%Filter for synapse Type
                synType = synfo.synType;
                if strcmp(synType,'ribbon')
                    synType = 'rib';
                elseif strcmp(synType,'conventional')
                    synType = 'syn';
                elseif strcmp(synType,'junction')
                    synType = 'junc';
                end

                if ~strcmp(synType,'all')
                    isType = find(strcmp(tis.syn.edgeTag,synType));
                    isSyn = intersect(isSyn,isType);
                end

                if synfo.preTypeID>0
                    isPreType = find(tis.syn.preClass == synfo.preTypeID);
                    isSyn = intersect(isSyn,isPreType);
                end
                if synfo.postTypeID>0
                    isPostType = find(tis.syn.postClass == synfo.postTypeID);
                    isSyn = intersect(isSyn,isPostType);
                end


                % pos = tis.syn.synPosDS(isSyn,[2 1 3]) * glob.em.dsRes(1);
                pos = tis.syn.pos(isSyn,[2 1 3]);


                %%Translate positions
                if isfield(glob.vol,'shiftZ')
                    dsPos = pos / tis.obI.em.dsRes;
                    zInd = ceil(dsPos(:,3));
                    zInd(zInd<1) = 1;
                    shiftZ = glob.vol.shiftZ.shifts;
                    shiftZ = shiftZ * tis.obI.em.dsRes;
                    maxPos = max(zInd);
                    if maxPos>length(shiftZ)
                        shiftZ(maxPos,:) = [0 0];
                    end
                    yshift = shiftZ(zInd,2);
                    xshift = shiftZ(zInd,1);
                    pos(:,1) = pos(:,1) + yshift;
                    pos(:,2) = pos(:,2) + xshift;
                end


                synfo.pos = pos;
                synfo.synIdx = isSyn;

                preStr =  get(glob.handles.edit_synPreGroup,'String');
                postStr = get(glob.handles.edit_synPostGroup,'String');

                if isempty(preStr),preStr = 'all';end
                if isempty(postStr),postStr = 'all';end
                synfo.name = sprintf('[ %s --> %s ] synType = %s',preStr, postStr, synfo.synType);
                synText = num2str([pre(isSyn) post(isSyn)]);
            end

            set(glob.handles.mainAx, 'NextPlot', 'add');
            if ~isempty(pos)
                synfo.p = scatter3(glob.ax,pos(:,1),pos(:,2),pos(:,3),synfo.markerSize,...
                    'markerfacecolor',synfo.col,'markerfacealpha',synfo.alph,...
                    'marker',synfo.markerType,'markeredgecolor','w',...
                    'linewidth',2,'MarkerEdgeAlpha',synfo.alph );
            end

            try
                set(synfo.p,'clipping','off');
            end

            gStrs = app.popupmenu_synGroup.Items;

            gStrs{L} = synfo.name;
            app.popupmenu_synGroup.Items = gStrs;
            app.popupmenu_synGroup.ItemsData = 1:length(gStrs);

            app.popupmenu_synGroup.Value = L ;

            showText(app, synText);
            glob.syn.g(L) = synfo;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function cellNavGuide_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to cellNavGuide (see VARARGIN)

            % Choose default command line output for cellNavGuide
            handles.output = hObject;

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes cellNavGuide wait for user response (see UIRESUME)
            % uiwait(glob.fig);

            global glob tis

            scrsz = get(0,'ScreenSize');
            winX = scrsz(3) * .6;
            winY = scrsz(4) * .8;
            floatingFigure(app)
            glob.fig.Position = [scrsz(3)-winX-100 scrsz(4)-winY-100 winX winY];
            %glob.ax = gca(glob.fig);
            glob.handles = handles;
            glob.app = app;
            glob.data.path.Parent = glob.fig;%glob.handles.figure1;

            z = zoom(glob.ax);
            setAxes3DPanAndZoomStyle(z,glob.ax,'limits')


            [a e ] = view(glob.ax);
            glob.light = lightangle(glob.ax,a+45,e + 45);

            %%Former createfunctions
            app.listCIDs.Items = glob.cellStr(glob.listCellidx) ;
            app.listCIDs.ItemsData = 1:length(glob.cellStr(glob.listCellidx)) ;

            view(glob.ax,[0 0])
            axis off
            set(glob.ax,'color',[0 0 0])
            set(glob.ax,'visible','off')

            if glob.typeID < 1
                subTypeNames = {'all'};
            else
                subTypeNames = tis.cells.type.subTypeNames{glob.typeID};
            end
            app.subType_popupMenue.Items = subTypeNames;
            app.subType_popupMenue.ItemsData = 1:length(subTypeNames);

            app.subColor_popUp.Items = glob.colorOptions;
            app.subColor_popUp.ItemsData = 1:length(glob.colorOptions);
            app.listbox3.Items = glob.colorOptions;
            app.listbox3.ItemsData = 1:length(glob.colorOptions);
            app.listbox_preCol.Items = glob.colorOptions;
            app.listbox_preCol.ItemsData = 1:length(glob.colorOptions);

            glob.g = glob.defaultG;
            str = {glob.g.name};
            app.renderGroups_popUp.Items = str;
            app.renderGroups_popUp.ItemsData = 1:length(str);

            alphaOptions = {'0' '.1' '.2' '.3' '.4' '.5' '.5' '.6' '.7' '.8' '.9' '1'};
            app.subAlpha_popUp.Items = alphaOptions;
            app.subAlpha_popUp.ItemsData = 1:length(alphaOptions);
            app.subAlpha_popUp.Value = glob.syn.g.alphIdx;

            app.popupmenu_funcTypes.Items = glob.com.typeStrings;
            app.popupmenu_funcTypes.ItemsData = 1:length(glob.com.typeStrings);
            app.popupmenu_funcTypes.Value = 1;

            app.popupmenu_functions.Items =glob.com.functionFiles;
            app.popupmenu_functions.ItemsData = 1:length(glob.com.functionFiles);


            app.edit13.Value =glob.save.fileName;

            app.edit_saveDirectory.Value =glob.save.dir;
            app.popupmenu_renderFunctions.Items =glob.save.functions;
            app.popupmenu_renderFunctions.ItemsData =1:length(glob.save.functions);


            % app.popupmenu_synPostType.Items =glob.typeStrings;

            str = {'o','+','*','.','x','square','diamond','^','V','<','>','pentagram','hexagram','none'};
            app.popupmenu_synMarker.Items =str;
            app.popupmenu_synMarker.ItemsData = 1:length(str);
            app.popupmenu_synMarker.Value = 1;

            app.popupmenu_synMarkerColor.Items =glob.colorOptions;
            app.popupmenu_synMarkerColor.ItemsData =1:length(glob.colorOptions);
            app.popupmenu_synMarkerColor.Value = glob.syn.g.colIdx;

            app.popupmenu_synGroup.Items = {glob.syn.g.name};
            app.popupmenu_synGroup.ItemsData = 1:length({glob.syn.g.name});
            app.popupmenu_synGroup.Value = 1;

            try
                app.popupmenu_postClassSynapse.Items = cat(1,{'any'}, tis.cells.type.typeNames(:));
                app.popupmenu_postClassSynapse.ItemsData = 0:length(tis.cells.type.typeNames);
                app.popupmenu_postClassSynapse.Value = 0;
                app.popupmenu_preClassSynapse.Items = cat(1,{'any'}, tis.cells.type.typeNames(:));
                app.popupmenu_preClassSynapse.ItemsData = 0:length(tis.cells.type.typeNames);
                app.popupmenu_preClassSynapse.Value = 0;
            end

            %             str = {'Import Data','Generate point to point graphs','Translate List','Create Custom Object', 'Create Custom Group',...
            %                 'Model Transmission','Transform Volume','Copy cellNav','Cell Table'};
            %             app.popupmenu_selectWindows.Items =str;

            str = {'IPL Borders', 'Scale Bar', 'All Bipolars'};
            app.popupmenu_OtherObjects.Items = str;
            app.popupmenu_OtherObjects.ItemsData = 1:length(str);

            str = {'Main',glob.vol.names{:}};
            app.popupmenu_sourceVolume.Items = str;
            app.popupmenu_sourceVolume.ItemsData = 1:length(str);

            app.popupCellType.Items = glob.typeStrings;
            app.popupCellType.ItemsData = 1:length(glob.typeStrings);

        end

        % Value changed function: addRef_toggleButton
        function addRef_toggleButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addRef_toggleButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of addRef_toggleButton


            global glob tis

            if isfield(glob,'g')
                L = length(glob.g)+1;
            else
                L = 1;
            end


            if isfield(glob,'pickCIDref')

                refCids = glob.pickCIDref;

                idx = [];
                for i = 1 : length(refCids)
                    targ = find(tis.cids==refCids(i),1);
                    if ~isempty(targ)
                        idx = cat(1,idx,targ);
                    end
                end


                %%Delete old ref
                try
                    for r = 1:length(glob.p.refCell)
                        delete(glob.p.refCell(r))
                    end
                end
                glob.pickCIDref = [];


                %% make new group
                glob.g(L) = glob.defaultG;
                glob.g(L).idx = idx;
                glob.g(L).cid = tis.cids(idx);
                glob.g(L).col = repmat([rand rand rand],[length(glob.g(L).idx) 1]);
                glob.g(L).show = get(handles.togglebutton_showGroup,'Value')
                glob.g(L).name = sprintf('g%d - ref %s',L, num2str(refCids));
                showCellGroup(app, L)

                updateGroups(app)
                set(handles.edit2,'String','')
                edit2_function(app)


            end
        end

        % Menu selected function: customSynGroup
        function customSynGroup_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to customSynGroup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            customSynGroup
        end

        % Value changed function: deleteGroup
        function deleteGroup_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to deleteGroup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of deleteGroup

            global glob tis

            G = app.renderGroups_popUp.Value;

            if G>0
                try
                    for p = 1:length(glob.g(G).patch)
                        delete(glob.g(G).patch(p))
                    end
                end


                keep = setdiff([1:length(glob.g)],G);
                glob.g = glob.g(keep);

                try groupNames = {glob.g.name};
                catch ME
                    groupNames = [];
                end

                if isempty(groupNames)
                    glob.g = glob.defaultG;
                end

                updateGroups(app)

            end
        end

        % Value changed function: edit13
        function edit13_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit13 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit13 as text
            %        str2double(get(hObject,'String')) returns contents of edit13 as a double

            global glob

            glob.save.fileName = get(hObject,'String');
        end

        % Value changed function: edit1
        function edit1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit1 as text
            %        str2double(get(hObject,'String')) returns contents of edit1 as a double
            global glob

            str = get(hObject,'string');
            if iscell(str)
                str = str{1};
            end
            cid = str2num(str);
            idx = find(glob.cids==cid,1);

            if isempty(idx)
                set(handles.textOut,'String','cell(cid) not found');
            else


                glob.pickIdx = idx;
                glob.pickCID = cid;
                showPickedCell(app)
            end
        end

        % Value changed function: edit2
        function edit2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit2 as text
            %        str2double(get(hObject,'String')) returns contents of edit2 as a double
            edit2_function(app)
        end

        function   edit2_function(app)

            global glob tis

            str = get(app.edit2,'Value');

            if isempty(str)
                val = [];
            else
                if iscell(str)
                    str = str{1};
                end
                val = str2num(str);
            end


            glob.pickCIDref = val;


            try
                for r = 1:length(glob.p.refCell)
                    glob.p.refCell(r).delete
                end
            end

            for i = 1:length(val)

                fileName = sprintf('%s%d.mat',glob.useFvDir,val(i));
                fv = loadFV(fileName);;

                glob.p.refCell(i) = renderFVnav(fv,[1 0 0],1,val(i));

            end
        end

        % Value changed function: edit_alpha
        function edit_alpha_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit_alpha (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit_alpha as text
            %        str2double(get(hObject,'String')) returns contents of edit_alpha as a double

            global glob

            str  = get(hObject,'String');

            alph = str2num(str);
            glob.editPatch.alph = [];

            if ~isempty(alph)
                alph(alph<0) = 0;
                alph(alph>1) = 1;
                alph = alph(1);

                if size(alph,2) ~=1
                    set(handles.textOut,'String','alpha must be between 0 and 1')
                else
                    if get(handles.togglebutton_SelectPatch,'Value')
                        set(glob.editPatch.h,'FaceAlpha',alph);
                    end
                    glob.editPatch.alph = alph;
                end
            else
                set(handles.textOut,'String','alpha is empty')

            end
        end

        % Value changed function: edit_color
        function edit_color_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit_color (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit_color as text
            %        str2double(get(hObject,'String')) returns contents of edit_color as a double

            global glob

            glob.editPatch.col = [];

            str  = get(hObject,'String');
            if iscell(str)
                str = str{1};
            end

            col = str2num(str);
            col(col<0) = 0;
            col(col>1) = 1;
            col = round(col*100)/100;


            if size(col,2) ~=3
                set(handles.textOut,'String','color must be 1 x 3 RGB [1 1 1]')
            else
                if get(handles.togglebutton_SelectPatch,'Value')
                    set(glob.editPatch.h,'FaceColor',col);
                end
                glob.editPatch.col = col;
            end
        end

        % Value changed function: edit_eval
        function edit_eval_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit_eval (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit_eval as text
            %        str2double(get(hObject,'String')) returns contents of edit_eval as a double

            'hi'
        end

        % Value changed function: listCIDs
        function listCIDs_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to listCIDs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns listCIDs contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listCIDs

            global glob tis

            val = app.listCIDs.Value;
            glob.pickIdx = glob.listCellidx(val);
            glob.pickCID = tis.cids(glob.pickIdx);
            showPickedCell(app)
        end

        % Value changed function: listbox3
        function listbox3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to listbox3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns listbox3 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox3
            global glob
            pushPostCells_Callback(glob.handles.pushPostCells,[],glob.handles);
        end

        % Value changed function: listbox_preCol
        function listbox_preCol_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to listbox_preCol (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_preCol contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_preCol

            global glob
            pushPreCell_Callback(glob.handles.pushPreCell,[],glob.handles);
        end

        % Menu selected function: menuCellTable
        function menuCellTable_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to menuCellTable (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


            cellTable
        end

        % Menu selected function: menuCustomGroups
        function menuCustomGroups_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to menuCustomGroups (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            customGroup_App
            %showCell
        end

        % Menu selected function: menuDataPanel
        function menuDataPanel_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to menuDataPanel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


            global glob tisDat

            load([glob.useFvDir 'tisDat.mat']);
            set(handles.panelData,'Visible',1)
            set(handles.toggle_dataOn,'Value',1)
        end

        % Menu selected function: menuGraphPointToPoint
        function menuGraphPointToPoint_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to menuGraphPointToPoint (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            GraphPointToPoint
        end

        % Menu selected function: menuImport
        function menuImport_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to menuImport (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            ImportData
        end

        % Menu selected function: menuMakePath
        function menuMakePath_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to menuMakePath (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


            set(handles.uipanel_pathProperties,'Visible','on')
            set(handles.togglebutton_pathProperties,'Value',1)
            updatePathPanel(app)
        end

        % Menu selected function: menuObjectGroups
        function menuObjectGroups_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to menuObjectGroups (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


            customObject
        end

        % Menu selected function: menuTransformVolume
        function menuTransformVolume_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to menuTransformVolume (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            selectVolumeTransformation

        end

        % Menu selected function: menu_FindAppositions
        function menu_FindAppositions_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to menu_FindAppositions (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            findAppositions
        end

        % Menu selected function: menu_importSWC
        function menu_importSWC_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to menu_importSWC (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            importSWC
        end

        % Menu selected function: menuePointList
        function menuePointList_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to menuePointList (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


            translateList
        end

        % Button pushed function: nextCell
        function nextCell_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to nextCell (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global glob tis

            glob.pickIdx = min(glob.pickIdx + 1, length(glob.listCellidx));
            glob.pickCID = tis.cids(glob.pickIdx);
            set(handles.listCIDs,'Value',glob.pickIdx)

            showPickedCell(app)
        end

        % Value changed function: popupCellType
        function popupCellType_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            popupCellType_Function(app)

        end

        % Value changed function: popupmenu_OtherObjects
        function popupmenu_OtherObjects_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to popupmenu_OtherObjects (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_OtherObjects contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_OtherObjects
            global glob tis tisDat

            strs = app.popupmenu_OtherObjects.Items;
            val = app.popupmenu_OtherObjects.Value;
            str = lower(strs{val});

            uVal =  ~glob.param.ref(val);
            glob.param.ref(val) = uVal;

            if uVal
                strs{val} = upper(str);
            else
                strs{val} = lower(str);
            end
            set(hObject,'String',strs)


            switch str
                case lower('IPL Borders')

                    try
                        isHand = isvalid(glob.p.pRefINL);
                    catch err
                        isHand = 0;
                    end

                    if uVal
                        if isHand
                            glob.p.pRefGCL.Visible = 'on';
                            glob.p.pRefINL.Visible = 'on';
                        else
                            load([glob.useFvDir 'ref_gcl nucEdge.mat'])
                            glob.p.pRefGCL = renderFVnav(fv,[1 1 0],.5)

                            load([glob.useFvDir 'ref_inl nucEdge.mat'])
                            glob.p.pRefINL = renderFVnav(fv,[1 0 1],.5)
                        end
                    else
                        if isHand
                            glob.p.pRefGCL.Visible = 'off'
                            glob.p.pRefINL.Visible = 'off'
                        end
                    end

                case lower('Scale Bar')
                    try
                        isHand = isvalid(glob.p.pRefMicronBar);
                    catch err
                        isHand = 0;
                    end

                    if uVal
                        if isHand
                            glob.p.pRefMicronBar.Visible = 'on';
                            glob.p.pRefMicronPt1.Visible = 'on';
                            glob.p.pRefMicronPt2.Visible = 'on';
                        else
                            load([glob.useFvDir 'ref_10 micron bar.mat'])
                            glob.p.pRefMicronBar = renderFVnav(fv,[1 1 1],1)

                            load([glob.useFvDir 'ref_10 micron point 1.mat'])
                            glob.p.pRefMicronPt1 = renderFVnav(fv,[1 1 1],.5)

                            load([glob.useFvDir 'ref_10 micron point 2.mat'])
                            glob.p.pRefMicronPt2 = renderFVnav(fv,[1 1 1],.5)
                        end
                    else
                        if isHand
                            glob.p.pRefMicronBar.Visible = 'off';
                            glob.p.pRefMicronPt1.Visible = 'off';
                            glob.p.pRefMicronPt2.Visible = 'off';
                        end
                    end

                case lower('All Bipolars')

                    try
                        isHand = isvalid(glob.p.pBipolars);
                    catch err
                        isHand = 0;
                    end

                    if uVal
                        if isHand
                            glob.p.pBipolars.Visible = 'on';
                        else
                            bipCids = glob.cids(tis.cells.type.typeID == 7);
                            vert = [];
                            fac = [];
                            for i = 1:length(bipCids)
                                fileName = sprintf('%s%d.mat',glob.useFvDir,bipCids(i));
                                fv = loadFV(fileName);;
                                fac = cat(1,fac,fv.faces+size(vert,1));
                                vert = cat(1,vert,fv.vertices);
                            end

                            fv.vertices = vert;
                            fv.faces = fac;
                            glob.p.pBipolars = renderFVnav(fv,[0 .8 0],.1)

                        end
                    else
                        if isHand
                            glob.p.pBipolars.Visible = 'off';
                        end
                    end

            end
        end

        % Value changed function: popupmenu_funcTypes
        function popupmenu_funcTypes_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to popupmenu_funcTypes (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_funcTypes contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_funcTypes

            global glob

            val = app.popupmenu_funcTypes.Value;
            funcIDs = glob.com.typeFunctions{val};
            if val == 1
                funcStrs = glob.com.functionFiles;
            else

                if ~isempty(funcIDs)
                    funcStrs = glob.com.functions{funcIDs};
                else
                    funcStrs = ' ';
                end
            end
            set(handles.popupmenu_functions,'String',funcStrs,'Value',1);
        end

        % Value changed function: popupmenu_sourceVolume
        function popupmenu_sourceVolume_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to popupmenu_sourceVolume (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_sourceVolume contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_sourceVolume

            global glob tis tisDat

            strs = app.popupmenu_sourceVolume.Items;
            val = app.popupmenu_sourceVolume.Value;
            str = strs{val};

            if ~strcmp(str,'Main')
                glob.useFvDir = [glob.dir.Volumes str '\Analysis\fvLibrary\'];
                glob.NA.MPN = [glob.dir.Volumes str '\Merge\'];
                glob.NA.WPN = [glob.dir.Volumes str '\Analysis\'];
                % MPN = glob.NA.MPN;
                % save('MPN.mat','MPN');
            else
                glob.useFvDir = glob.fvDir;
            end
            
            makeMPNcnv
            glob.vol.activeID = length(strs);
            glob.vol.activeName = str;

            if exist([glob.dir.Volumes str '\Merge\shiftZ.mat']);
                load([glob.dir.Volumes str '\Merge\shiftZ.mat']);
                glob.vol.shiftPlane = shiftZ;
            else
                glob.vol.shiftPlane = 0;
            end

            
            if exist([glob.useFvDir 'tis.mat'],'file')
                load([glob.useFvDir 'tis.mat']);
            else
                tis = makeTis;
                save([glob.useFvDir 'tis.mat'],'tis');
            end
            try load([glob.useFvDir 'tisDat.mat']); end
            updateCellNavGlob
            
            app.popupCellType.Items = glob.typeStrings;
            app.popupCellType.ItemsData = [1:length(glob.typeStrings)];
            app.popupCellType.Value = 1;
            popupCellType_Function(app);
            

            if exist([glob.NA.MPN  'shiftZ.mat'])
                load([glob.NA.MPN  'shiftZ.mat']);
                glob.vol.shiftZ = shiftZ;
            end

        end

        % Value changed function: popupmenu_synGroup
        function popupmenu_synGroup_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to popupmenu_synGroup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_synGroup contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_synGroup
            global glob

            synGroup2Panel(app)
        end

        % Value changed function: popupmenu_synMarkerAlpha
        function popupmenu_synMarkerAlpha_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to popupmenu_synMarkerAlpha (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_synMarkerAlpha contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_synMarkerAlpha


            global glob tis

            G = app.popupmenu_synGroup.Value;
            alphOption = app.popupmenu_synMarkerAlpha.Value;
            alphStr = app.popupmenu_synMarkerAlpha.Items;

            alph = str2num(alphStr{alphOption});

            glob.syn.g(G).alph = alph;
            glob.syn.g(G).alphIdx = alphOption;

            drawSyn(app)
        end

        % Value changed function: popupmenu_synMarkerColor
        function popupmenu_synMarkerColor_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to popupmenu_synMarkerColor (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_synMarkerColor contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_synMarkerColor


            global glob tis
            G = app.popupmenu_synGroup.Value;


            L = 1;%length(glob.syn.g(G).idx);
            colOption = app.popupmenu_synMarkerColor.Value;
            glob.syn.g(G).col = getCol(app, colOption,L);
            glob.syn.g(G).colIdx = colOption;


            drawSyn(app)
        end

        % Value changed function: popupmenu_synMarker
        function popupmenu_synMarker_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to popupmenu_synMarker (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_synMarker contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_synMarker

            global glob

            G = app.popupmenu_synGroup.Value;

            str = get(hObject,'String');
            val = app.popupmenu_synMarker.Value;
            glob.syn.g(G).markerType = str{val};
            glob.syn.g(G).markerTypeIdx = val;


            drawSyn(app)
        end

        % Value changed function: popupmenu_synPostGroup
        function popupmenu_synPostGroup_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to popupmenu_synPostGroup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_synPostGroup contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_synPostGroup


            global glob

            %val = find(strcmp(app.popupmenu_synPostGroup.Items, app.popupmenu_synPostGroup.Value),1);
            %G = find(strcmp(app.popupmenu_synGroup.Items, app.popupmenu_synGroup.Value),1);
            G = app.popupmenu_synGroup.Value;
            val = app.popupmenu_synPostGroup.Value;

            glob.syn.g(G).postCellIdx = glob.g(val).idx;
            glob.syn.g(G).postName = glob.g(val).name;
            app.edit_synPostGroup.Value = glob.g(val).name;
            glob.syn.g(G)
            drawSyn(app)
        end

        % Value changed function: popupmenu_synPreGroup
        function popupmenu_synPreGroup_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to popupmenu_synPreGroup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_synPreGroup contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_synPreGroup

            global glob

            val = app.popupmenu_synPreGroup.Value;
            G = app.popupmenu_synGroup.Value;

            glob.syn.g(G).preCellIdx = glob.g(val).idx;
            glob.syn.g(G).preName = glob.g(val).name;
            set(handles.edit_synPreGroup,'String',glob.g(val).name);

            drawSyn(app)
        end

        % Value changed function: popupmenu_synSynapseType
        function popupmenu_synSynapseType_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to popupmenu_synSynapseType (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_synSynapseType contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_synSynapseType

            global glob

            val = app.popupmenu_synSynapseType.ValueIndex;
            strs = app.popupmenu_synSynapseType.Items;
            str = strs{val};

            G = app.popupmenu_synGroup.Value;
            glob.syn.g(G).synType = str;
            glob.syn.g(G).synTypeListIdx = val;

            drawSyn(app)
        end

        % Button pushed function: prevCell
        function prevCell_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to prevCell (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global glob tis
            glob.pickIdx = max(glob.pickIdx - 1, 1);
            glob.pickCID = tis.cids(glob.pickIdx);
            set(handles.listCIDs,'Value',glob.pickIdx)
            showPickedCell(app)
        end

        % Value changed function: pushDataTip
        function pushDataTip_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushDataTip (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of pushDataTip
            global glob

            val = get(hObject,'Value');

            % try
            %     glob.dcm.Enable = 'off'
            %     %glob.dcm.delete
            % end

            if val
                deselectToggleUI(app, handles, hObject)
                glob.dcm = datacursormode(glob.fig);
                glob.dcm.UpdateFcn = @app.displayCoordinates;

                set(glob.dcm,'DisplayStyle','datatip',...
                    'SnapToDataVertex','off','Enable','on')
            else
                rotate3d(glob.fig)
                delete(findall(glob.fig,'Type','hggroup'))
            end
        end

        % Button pushed function: pushGrowMarker
        function pushGrowMarker_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushGrowMarker (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


            global glob

            glob.param.markerSize = glob.param.markerSize ^ 1.1;
            glob.param.markerSize = max(1,glob.param.markerSize);
            pushPostSyn_Callback(glob.handles.pushPostSyn)
            pushPreSyn_Callback(glob.handles.pushPreSyn)
        end

        % Value changed function: pushPostCells
        function pushPostCells_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushPostCells (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global glob tis

            val = get(hObject,'Value');

            try
                for i = 1:length(glob.p.postCells)
                    delete(glob.p.postCells(i))
                end
            end

            if val

                cellCon(app)
                showCells = glob.con.isPost;

                colOption = app.listbox3.Value;
                col = getCol(app, colOption,length(showCells));
                for i = 1:length(showCells)
                    fileName = sprintf('%s%d.mat',glob.useFvDir,showCells(i));
                    if exist(fileName,'file')
                        fv = loadFV(fileName);;
                        glob.p.postCells(i) = renderFVnav(fv,col(i,:),.6)
                        glob.p.postCells(i).Tag = num2str(showCells(i));
                    end
                end

                postText = sprintf('post to %d = %s',glob.pickCID,num2str(glob.con.isPost'));
                showText(app, postText);


            end
        end

        % Value changed function: pushPostSyn
        function pushPostSyn_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushPostSyn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global glob tis

            val = get(hObject,'Value');

            try delete(glob.p.postSyn1)
            end
            try delete(glob.p.postSyn2)
            end
            if val

                targ = glob.pickCID;
                isSyn = find(tis.syn.pre == targ);
                pos = tis.syn.synPosDS(isSyn,[2 1 3])* glob.em.dsRes(1);
                postClass = tis.syn.postClass(isSyn);
                col = repmat([1 0 0],[length(isSyn) 1]);
                isRGC = find(postClass==1);
                hold on
                glob.p.postSyn1 = scatter3(glob.ax,pos(:,1),pos(:,2),pos(:,3),glob.param.markerSize,'markerfacecolor','m',...
                    'marker','o','markeredgecolor','w');
                glob.p.postSyn2 = scatter3(glob.ax,pos(isRGC,1),pos(isRGC,2),pos(isRGC,3),glob.param.markerSize,'markerfacecolor','b',...
                    'marker','o','markeredgealpha',0);
                set(glob.p.postSyn1,'clipping','off')
                set(glob.p.postSyn2,'clipping','off')

                hold off


                preSyn = tis.syn.post(isSyn);
                %postText = sprintf('postSyn to %d = %s',targ,num2str(preSyn'));

                postText = sprintf('allPost = %d.  RGC post = %d',length(isSyn),length(isRGC));

                showText(app, postText);

            end
        end

        % Value changed function: pushPreCell
        function pushPreCell_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushPreCell (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global glob tis

            val = get(hObject,'Value');

            try
                for i = 1:length(glob.p.preCells)
                    delete(glob.p.preCells(i));
                end
            end


            if val

                cellCon(app)
                showCells = glob.con.isPre;

                colOption = get(handles.listbox_preCol,'Value');
                col = getCol(app, colOption,length(showCells));
                for i = 1:length(showCells)
                    fileName = sprintf('%s%d.mat',glob.useFvDir,showCells(i));
                    if exist(fileName,'file')
                        fv = loadFV(fileName);;
                        glob.p.preCells(i) = renderFVnav(fv,col(i,:),.6)
                        glob.p.preCells(i).Tag = num2str(showCells(i));
                    end
                end

                preText = sprintf('pre to %d = %s',glob.pickCID,num2str(glob.con.isPre'));
                showText(app, preText);

            end
        end

        % Value changed function: pushPreSyn
        function pushPreSyn_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushPreSyn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global glob tis

            val = get(hObject,'Value');

            try
                delete(glob.p.preSyn1)
            end
            try
                delete(glob.p.preSyn2)
            end
            if val

                targ = glob.pickCID;
                isSyn = find(tis.syn.post == targ);
                pos = tis.syn.synPosDS(isSyn,[2 1 3]) * glob.em.dsRes(1);
                preClass = tis.syn.preClass(isSyn);
                col = repmat([1 0 0],[length(isSyn) 1]);
                isBip = find(preClass==7);
                hold on
                glob.p.preSyn1 = scatter3(glob.ax,pos(:,1),pos(:,2),pos(:,3),glob.param.markerSize,'markerfacecolor','r',...
                    'marker','o','markeredgecolor','w');
                glob.p.preSyn2 = scatter3(glob.ax,pos(isBip,1),pos(isBip,2),pos(isBip,3),glob.param.markerSize,'markerfacecolor','g',...
                    'marker','o','markeredgealpha',0);
                set(glob.p.preSyn1,'clipping','off')
                set(glob.p.preSyn2,'clipping','off')

                hold off

                preSyn = tis.syn.pre(isSyn);
                %postText = sprintf('preSyn to %d = %s',targ,num2str(preSyn'));
                postText = sprintf('all Pre = %d.  Bip post = %d',length(isSyn),length(isBip));

                showText(app, postText);
            end
        end

        % Button pushed function: pushShrinkMarker
        function pushShrinkMarker_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushShrinkMarker (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global glob

            glob.param.markerSize = glob.param.markerSize ^ .9;
            glob.param.markerSize = max(1,glob.param.markerSize);
            pushPostSyn_Callback(glob.handles.pushPostSyn)
            pushPreSyn_Callback(glob.handles.pushPreSyn)
        end

        % Button pushed function: pushbutton24
        function pushbutton24_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton24 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global glob
            G = app.popupmenu_synGroup.Value;
            glob.syn.g(G).markerSize = glob.syn.g(G).markerSize ^ .9;
            glob.syn.g(G).markerSize = max(1,glob.syn.g(G).markerSize);
            drawSyn(app)
        end

        % Button pushed function: pushbutton25
        function pushbutton25_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton25 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


            global glob

            G = app.popupmenu_synGroup.Value;


            glob.syn.g(G).markerSize = glob.syn.g(G).markerSize ^ 1.1;
            glob.syn.g(G).markerSize = max(1,glob.syn.g(G).markerSize);
            drawSyn(app)
        end

        % Button pushed function: pushbutton_Function
        function pushbutton_Function_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_Function (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global glob
            str = get(handles.popupmenu_functions,'String');
            app.popupmenu_functions.Value;
            if iscell(str)
                str = str{get(handles.popupmenu_functions,'Value')};
            end

            glob.com.result = '';
            try
                output = evalc(str);
            catch err
                output = err.message
            end
            set(handles.edit_result,'String',output)


            pause(.5)
            if length(glob.com.result)>0
                set(handles.edit_result,'String',glob.com.result)
            end
        end

        % Button pushed function: pushbutton_SelectDir
        function pushbutton_SelectDir_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_SelectDir (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


            if exist('.\LastSaveDir.mat')
                load(['.\LastSaveDir.mat']);
                if exist('LastSaveDir','var')
                    TPN = uigetdir(LastSaveDir);
                else
                    TPN=uigetdir;
                end
            else
                TPN=uigetdir;
            end
            LastSaveDir= [TPN '\'];

            if LastSaveDir>0
                save('.\LastSaveDir.mat','LastSaveDir')
            end
            glob.save.dir = LastSaveDir;

            set(handles.edit_saveDirectory,'String',glob.save.dir);
        end

        % Button pushed function: pushbutton_blendPatches
        function pushbutton_blendPatches_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_blendPatches (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            blendPatches(app)
        end

        % Button pushed function: pushbutton_clearPath
        function pushbutton_clearPath_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_clearPath (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global glob

            glob.data.path.pts = [];
            glob.data.path.pos = [];
            glob.data.path.lengths = [];
            delete(findall(glob.fig,'Type','hggroup'));
            updatePathPanel(app)
            try
                delete(glob.data.path.plotH);
            end
        end

        % Button pushed function: pushbutton_eval
        function pushbutton_eval_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_eval (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global glob
            str = get(handles.edit_eval,'String')

            glob.com.result = '';
            try
                output = evalc(str);
            catch err
                output = err.message
            end
            set(handles.edit_result,'String',output)
            pause(.5)
            if length(glob.com.result)>0
                set(handles.edit_result,'String',glob.com.result)
            end
        end

        % Button pushed function: pushbutton_loadGlob
        function pushbutton_loadGlob_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_loadGlob (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global glob
            [TFN TPN ] = uigetfile(glob.save.defaultDir);

            close (glob.handles.figure1)
            load([TPN TFN]);
        end

        % Button pushed function: pushbutton_makeTable
        function pushbutton_makeTable_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_makeTable (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


            global glob tis

            glob.windows.cellTable = cellTable;
        end

        % Button pushed function: pushbutton_postGroup
        function pushbutton_postGroup_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_postGroup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global glob tis

            if isfield(glob,'g')
                L = length(glob.g)+1;
            else
                L = 1;
            end


            if ~isempty(glob.con.isPost)
                cellCon(app)
                refCids = glob.con.isPost;

                idx = [];
                for i = 1 : length(refCids)
                    targ = find(tis.cids==refCids(i),1);
                    if ~isempty(targ)
                        idx = cat(1,idx,targ);
                    end
                end


                %% make new group
                glob.g(L) = glob.defaultG;
                glob.g(L).idx = idx;
                glob.g(L).cid = tis.cids(idx);
                glob.g(L).col = repmat([rand rand rand],[length(glob.g(L).idx) 1]);
                glob.g(L).show = get(handles.togglebutton_showGroup,'Value')
                glob.g(L).name = sprintf('g%d - post to %d',L, glob.pickCID);
                showCellGroup(app, L)

                updateGroups(app)
                set(handles.edit2,'String','')
                edit2_function(app);


            end
        end

        % Button pushed function: pushbutton_preGroup
        function pushbutton_preGroup_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_preGroup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global glob tis

            if isfield(glob,'g')
                L = length(glob.g)+1;
            else
                L = 1;
            end


            if ~isempty(glob.con.isPre)
                cellCon(app)
                refCids = glob.con.isPre;

                idx = [];
                for i = 1 : length(refCids)
                    targ = find(tis.cids==refCids(i),1);
                    if ~isempty(targ)
                        idx = cat(1,idx,targ);
                    end
                end


                %% make new group
                glob.g(L) = glob.defaultG;
                glob.g(L).idx = idx;
                glob.g(L).cid = tis.cids(idx);
                glob.g(L).col = repmat([rand rand rand],[length(glob.g(L).idx) 1]);
                glob.g(L).show = get(handles.togglebutton_showGroup,'Value')
                glob.g(L).name = sprintf('g%d - pre to %d',L, glob.pickCID);
                showCellGroup(app, L)

                updateGroups(app)
                set(handles.edit2,'String','')
                edit2_function(app);


            end
        end

        % Value changed function: pushbutton_resetZoom
        function pushbutton_resetZoom_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_resetZoom (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global glob

            set(glob.handles.mainAx,'color',[0 0 0])
            set(glob.handles.mainAx,'visible','off')
            set(glob.handles.mainAx,'View',[0 0])

            val = get(hObject,'Value');
            if val
                set(glob.handles.mainAx,'CameraPositionMode','auto')
                set(glob.handles.mainAx,'CameraTargetMode','auto')
                set(glob.handles.mainAx,'CameraViewAngleMode','auto')
            else
                set(glob.handles.mainAx,'CameraPositionMode','manual')
                set(glob.handles.mainAx,'CameraTargetMode','manual')
                set(glob.handles.mainAx,'CameraViewAngleMode','manual')
            end
        end

        % Button pushed function: pushbutton_runRender
        function pushbutton_runRender_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_runRender (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global glob

            str = get(handles.popupmenu_renderFunctions,'String');
            val = get(handles.popupmenu_renderFunctions,'Value');
            str = str{val};
            eval(str)
        end

        % Button pushed function: pushbutton_saveGlob
        function pushbutton_saveGlob_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_saveGlob (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


            global glob
            str = get(glob.handles.edit13,'String');
            set(glob.handles.edit13,'String','Saving Figure ....')

            glob.figData = guidata(glob.handles.figure1);
            save([glob.save.defaultDir glob.save.fileName '.mat'],'glob')
            set(glob.handles.edit13,'String',glob.save.fileName )
        end

        % Button pushed function: pushbutton_snapShot
        function pushbutton_snapShot_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_snapShot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            takeSnapShot(app)


        end

        % Button pushed function: pushbutton_synDelete
        function pushbutton_synDelete_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_synDelete (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global glob

            val = app.popupmenu_synGroup.Value;
            keep = setdiff(1:length(glob.syn.g),val);

            try
                delete(glob.syn.g(val).p);
            end

            if length(keep)>0
                glob.syn.g = glob.syn.g(keep);
                names = {glob.syn.g.name};
                app.popupmenu_synGroup.Items =names;
                app.popupmenu_synGroup.ItemsData =1:length(names);
                app.popupmenu_synGroup.Value = length(names);
            else

                glob.syn.g = glob.syn.defaultG;
            end
            synGroup2Panel(app);
        end

        % Button pushed function: pushbutton_synMakeGroup
        function pushbutton_synMakeGroup_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_synMakeGroup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


            global glob tis

            L = length(glob.syn.g)+1;
            glob.syn.g(L) = glob.syn.defaultG;
            %app.popupmenu_synGroup

            groupName = 'New synapse group';
            groupNames = app.popupmenu_synGroup.Items;
            groupNames{L} = groupName;
            app.popupmenu_synGroup.Items = groupNames;
            %app.popupmenu_synGroup.Value = groupNames{L};
            app.popupmenu_synGroup.ItemsData = [1:length(groupNames)];
            app.popupmenu_synGroup.Value = L;
            synGroup2Panel(app)
            drawSyn(app)
        end

        % Button pushed function: pushbutton_undoPath
        function pushbutton_undoPath_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_undoPath (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global glob

            L = length(glob.data.path.pos);

            if L>1
                glob.data.path.pos = glob.data.path.pos(1:end-1,:);
                glob.data.path.pts = glob.data.path.pts(1:end-1,:);

                pos = glob.data.path.pos;

                L = sqrt((pos(2:end,1)-pos(1:end-1,1)).^2 + (pos(2:end,2)-pos(1:end-1,2)).^2 + ...
                    (pos(2:end,3)-pos(1:end-1,3)).^2);
                glob.data.path.lengths = L;

                pts = glob.data.path.pts;
                textOutStr = num2str(round(pos));

                try
                    delete(glob.data.path.plotH);
                end
                set(glob.data.path.Parent, 'NextPlot', 'add')

                glob.data.path.plotH = plot3(glob.data.path.Parent,...
                    pts(:,1),pts(:,2),pts(:,3),...
                    'linewidth',10,'color','g');

                clipboard('copy',sum(L))
                txt = sprintf('%0.2f um',sum(L));


            end

            if get(glob.handles.togglebutton_pathProperties,'Value')
                updatePathPanel(app)
            end
            plotPath(app)
            glob.data.path
        end

        % Button pushed function: raiseLight
        function raiseLight_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to raiseLight (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global glob

            a = rand * 360 - 180;
            e = rand * 180 - 90;
            lightangle(glob.light,a,e);
        end

        % Value changed function: renderGroups_popUp
        function renderGroups_popUp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to renderGroups_popUp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns renderGroups_popUp contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from renderGroups_popUp

            global glob tis
            L = app.renderGroups_popUp.Value;
            app.subColor_popUp.Value = glob.g(L).colIdx;
            app.subAlpha_popUp.Value = glob.g(L).alphIdx;
            set(handles.togglebutton_showGroup,'Value',glob.g(L).show);

        end

        % Menu selected function: runEditSkeleton
        function runEditSkeleton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to runEditSkeleton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            editSkeleton_AppFig
            %editSkeleton_App
        end

        % Value changed function: showSub_toggle
        function showSub_toggle_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to showSub_toggle (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of showSub_toggle


            global glob tis

            if isfield(glob,'g')
                L = length(glob.g)+1;
            else
                L = 1;
            end

            glob.g(L) = glob.defaultG;
            glob.g(L).idx = glob.listCellidx;
            glob.g(L).cid = tis.cids(glob.listCellidx);
            glob.g(L).col = repmat([rand rand rand],[length(glob.g(L).idx) 1]);
            glob.g(L).show = get(handles.togglebutton_showGroup,'Value')


            if glob.typeID>0
                typeName = tis.cells.type.typeNames{glob.typeID};

                if glob.subTypeID>0
                    subTypeName = tis.cells.type.subTypeNames{glob.typeID}{glob.subTypeID};
                else
                    subTypeName = '-';
                end

            else
                typeName = '-';
                subTypeName = '-';
            end


            glob.g(L).name = sprintf('g%d - %s %s',L,typeName,subTypeName);
            showCellGroup(app, L)

            updateGroups(app)
        end

        % Value changed function: subAlpha_popUp
        function subAlpha_popUp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to subAlpha_popUp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns subAlpha_popUp contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from subAlpha_popUp

            global glob tis
            G = app.renderGroups_popUp.Value;

            L = length(glob.g(G).idx);

            alphOption = app.subAlpha_popUp.Value

            alph = (alphOption -1) /11

            glob.g(G).alph = alph;

            for i = 1:length(glob.g(G).patch)
                set(glob.g(G).patch(i),'FaceAlpha',alph);
            end
        end

        % Value changed function: subColor_popUp
        function subColor_popUp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to subColor_popUp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns subColor_popUp contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from subColor_popUp

            global glob tis
            G = app.renderGroups_popUp.Value;

            L = length(glob.g(G).idx);
            colOption = app.subColor_popUp.Value;
            glob.g(G).col = getCol(app, colOption,L);

            for p = 1:length(glob.g(G).patch)
                set(glob.g(G).patch(p),'FaceColor',glob.g(G).col(p,:));
            end
        end

        % Value changed function: subType_popupMenue
        function subType_popupMenue_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>


            subType_popupMenue_Function(app);

        end

        % Value changed function: textCID
        function textCID_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to textCID (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of textCID
            textCID_function(app)

        end

        function textCID_function(app)
            global glob

            val = app.textCID.Value;

            if val
                try
                    set(glob.p.cell,'Visible','on')
                catch
                    showPickedCell(app)
                end
            else
                try
                    set(glob.p.cell,'Visible','off')
                end
            end
        end

        % Value changed function: textRefCell
        function textRefCell_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to textRefCell (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of textRefCell

            global glob

            val = get(hObject,'Value');

            for i = 1:length(glob.p.refCell)
                set(glob.p.refCell(i),'Visible',val)
            end
        end

        % Value changed function: toggleRadius
        function toggleRadius_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to toggleRadius (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of toggleRadius

            global glob

            if 0

                try
                    delete(glob.p.radius)    ;
                end
                glob.p.cell.FaceAlpha = 1;


                val = get(hObject,'Value');

                if val
                    fileName = sprintf('%sskelFV_%d.mat',glob.useFvDir,glob.pickCID)
                    if exist(fileName,'file')
                        fv = loadFV(fileName);
                        fv.vertices = fv.vertices(:,[3 1 2]) ;
                        glob.p.radius = renderFVnav(fv,[1 1 0],.6);
                    end

                    glob.p.cell.FaceAlpha = .25;

                else
                    try        glob.p.cell.FaceAlpha = 1;
                    end

                end

            else
                disp('disabled')
            end
        end

        % Value changed function: toggleSkeleton
        function toggleSkeleton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to toggleSkeleton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of toggleSkeleton

            global glob

            try
                delete(glob.p.skel)   ;
                delete(glob.p.plotSkel);
                delete(glob.p.scatterSkel);
            end
            try
                glob.p.cell.FaceAlpha = 1;
            end

            val = get(hObject,'Value');

            if val
                fileName = sprintf('%sskelFV_%d.mat',glob.useFvDir,glob.pickCID)
                if exist(fileName,'file')
                    fv = loadFV(fileName);
                    fv.vertices = fv.vertices(:,[3 1 2]) ;
                    glob.p.skel = renderFVnav(fv,[1 1 0],.6);
                    glob.p.skel.FaceAlpha = .25;
                end

                try
                    glob.p.cell.FaceAlpha = .25;
                end

                if 1 % show skel with edges and nodes
                    fileName = sprintf('%snep_%d.mat',glob.useFvDir,glob.pickCID)
                    if exist(fileName,'file')
                        %fv = loadFV(fileName);
                        load(fileName)
                        hold on
                        pos = nep.pos;
                        edges = nep.edges;
                        glob.p.scatterSkel = scatter3(glob.ax,pos(:,2),pos(:,1),pos(:,3),15,'o',...
                            'w','filled');
                        glob.p.plotSkel = plot3([pos(edges(:,1),2) pos(edges(:,2),2)]',...
                            [pos(edges(:,1),1) pos(edges(:,2),1)]',...
                            [pos(edges(:,1),3) pos(edges(:,2),3)]','color',[1 0 0],'linewidth',1);
                        set(gca,'Clipping','Off');
                        hold off
                    end
                end

            else
                try        glob.p.cell.FaceAlpha = 1;
                end

            end
        end

        % Value changed function: toggle_CellData
        function toggle_CellData_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to toggle_CellData (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of toggle_CellData

            global glob tisDat

            val = get(hObject,'Value');

            try
                delete(glob.dat.h.cell)
            end

            if val
                functionToggelCellData(app)
                %                 cid = glob.pickCID
                %                 cellIdx = glob.pickIdx;
                %                 cellDat = tisDat.depthHist(cellIdx);
                %                 depth = cellDat.histDat(:,1);
                %                 surface = cellDat.histDat(:,2);
                %                 surface = surface/max(surface);
                %
                %                 glob.dat.h.cell = plot(handles.axesDepth,surface,depth,...
                %                     'Color',[0 0 0],'linewidth',2);
                %                 set(handles.axesDepth,'XLim',[0 1],'YLim',[-.5 1.5],'ydir','reverse',...
                %                     'YTick',[-.5:.1:1.5])
            end
        end

        % Callback function
        function toggle_dataOn_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to toggle_dataOn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of toggle_dataOn

            global glob tisDat

            load([glob.useFvDir 'tisDat.mat']);
            val = get(hObject,'Value');
            set(handles.panelData,'Visible',val)
        end

        % Value changed function: toggle_groupData1
        function toggle_groupData1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to toggle_groupData1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of toggle_groupData1

            global glob tisDat

            val = get(hObject,'Value');

            if val
                groupID = get(handles.renderGroups_popUp,'Value');

                idxs = glob.g(groupID).idx;
                hold on
                for i = 1:length(idxs)
                    cellIdx = idxs(i);
                    col  = glob.g(groupID).col(i,:);
                    cellDat = tisDat.depthHist(cellIdx);
                    if cellDat.isDat
                        depth = cellDat.histDat(:,1);
                        surface = cellDat.histDat(:,2);
                        surface = surface/max(surface);
                        set(handles.axesDepth, 'NextPlot', 'add')
                        glob.dat.h.g1(i) = plot(handles.axesDepth,surface,depth,...
                            'Color',col','linewidth',2);
                    end
                    set(handles.axesDepth,'XLim',[0 1],'YLim',[-.5 1.5],'ydir','reverse',...
                        'YTick',[-.5:.1:1.5])
                end

            else
                try
                    for i = 1:length(glob.dat.h.g1)
                        delete(glob.dat.h.g1(i))
                    end
                end
            end
        end

        % Value changed function: toggle_groupData2
        function toggle_groupData2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to toggle_groupData2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of toggle_groupData2


            global glob tisDat

            val = get(hObject,'Value');

            if val
                groupID = app.renderGroups_popUp.Value;

                idxs = glob.g(groupID).idx;
                hold on
                for i = 1:length(idxs)
                    cellIdx = idxs(i);
                    col  = glob.g(groupID).col(i,:);
                    cellDat = tisDat.depthHist(cellIdx);
                    if cellDat.isDat
                        depth = cellDat.histDat(:,1);
                        surface = cellDat.histDat(:,2);
                        surface = surface/max(surface);
                        set(handles.axesDepth, 'NextPlot', 'add')
                        glob.dat.h.g2(i) = plot(handles.axesDepth,surface,depth,...
                            'Color',col','linewidth',2);
                    end
                    set(handles.axesDepth,'XLim',[0 1],'YLim',[-.5 1.5],'ydir','reverse',...
                        'YTick',[-.5:.1:1.5])
                end

            else
                try
                    for i = 1:length(glob.dat.h.g1)
                        delete(glob.dat.h.g2(i))
                    end
                end
            end
        end

        % Value changed function: toggle_highlightCell
        function toggle_highlightCell_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to toggle_highlightCell (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of toggle_highlightCell

            global glob
            val = get(hObject,'Value');


            if val
                deselectToggleUI(app, handles, hObject)
                glob.dcm = datacursormode(glob.fig);
                glob.dcm.UpdateFcn = @app.highlightCell;

                set(glob.dcm,'DisplayStyle','datatip',...
                    'SnapToDataVertex','off','Enable','on')
            else
                rotate3d(glob.fig)
                delete(findall(glob.fig,'Type','hggroup'))
                try
                    set(glob.highlight.oldPatch,...
                        'FaceColor', glob.highlight.oldColor,...
                        'FaceAlpha',glob.highlight.oldAlpha )
                end
            end
        end

        % Value changed function: toggle_refDat
        function toggle_refDat_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to toggle_refDat (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of toggle_refDat
            global glob tisDat

            val = get(hObject,'Value');

            if val

                if isfield(glob,'pickCIDref')
                    refCids = glob.pickCIDref;

                    for i = 1:length(refCids)
                        idxs(i) = find(glob.cids==refCids(i),1);
                    end

                    for i = 1:length(idxs)
                        cellIdx = idxs(i);
                        col  = [1 0 0];
                        cellDat = tisDat.depthHist(cellIdx);
                        if cellDat.isDat
                            depth = cellDat.histDat(:,1);
                            surface = cellDat.histDat(:,2);
                            surface = surface/max(surface);
                            set(handles.axesDepth, 'NextPlot', 'add')
                            glob.dat.h.ref(i) = plot(handles.axesDepth,surface,depth,...
                                'Color',col','linewidth',2);
                        end
                        set(handles.axesDepth,'XLim',[0 1],'YLim',[-.5 1.5],'ydir','reverse',...
                            'YTick',[-.5:.1:1.5])
                    end
                end

            else
                try
                    for i = 1:length(glob.dat.h.ref)
                        delete(glob.dat.h.ref(i))
                    end
                end
            end
        end

        % Value changed function: toggle_selectCell
        function toggle_selectCell_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to toggle_selectCell (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of toggle_selectCell

            global glob
            val = get(hObject,'Value');

            if val
                deselectToggleUI(app, handles, hObject)
                glob.dcm = datacursormode(glob.fig);
                glob.dcm.UpdateFcn = @app.cursorSelectCell;

                set(glob.dcm,'DisplayStyle','datatip',...
                    'SnapToDataVertex','off','Enable','on')
            else
                rotate3d(glob.fig)
                delete(findall(glob.fig,'Type','hggroup'))
                try
                    set(glob.highlight.oldPatch,...
                        'FaceColor', glob.highlight.oldColor,...
                        'FaceAlpha',glob.highlight.oldAlpha )
                end
            end
        end

        % Value changed function: togglebutton_PaintPatch
        function togglebutton_PaintPatch_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_PaintPatch (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_PaintPatch

            global glob
            val = get(hObject,'Value');

            if val
                deselectToggleUI(app, handles, hObject)
                glob.dcm = datacursormode(glob.fig);
                glob.dcm.UpdateFcn = @app.cursorPaintPatch;

                set(glob.dcm,'DisplayStyle','datatip',...
                    'SnapToDataVertex','off','Enable','on')
            else
                rotate3d(glob.fig)
                delete(findall(glob.fig,'Type','hggroup'))
            end
        end

        % Value changed function: togglebutton_SelectPatch
        function togglebutton_SelectPatch_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_SelectPatch (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_SelectPatch

            global glob
            val = get(hObject,'Value');

            if val
                deselectToggleUI(app, handles, hObject)
                glob.dcm = datacursormode(glob.fig);
                glob.dcm.UpdateFcn = @app.cursorSelectPatch;

                set(glob.dcm,'DisplayStyle','datatip',...
                    'SnapToDataVertex','off','Enable','on')
            else
                rotate3d(glob.fig)
                delete(findall(glob.fig,'Type','hggroup'))
            end
        end

        % Callback function
        function togglebutton_SynapsePanel_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_SynapsePanel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_SynapsePanel

            val = get(hObject,'Value');
            set(handles.uipanel_syn,'Visible',val);
        end

        % Value changed function: togglebutton_cell2group
        function togglebutton_cell2group_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_cell2group (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_cell2group


            global glob tis

            if isfield(glob,'g')
                L = length(glob.g)+1;
            else
                L = 1;
            end


            if isfield(glob,'pickCID')

                cid = glob.pickCID;
                idx = find(tis.cids==cid,1);

                glob.g(L) = glob.defaultG;
                glob.g(L).idx = glob.pickIdx;
                glob.g(L).cid = cid;
                %glob.g(L).col = glob.p.cell.FaceColor;
                %glob.g(L).alph = glob.p.cell.FaceAlpha;
                glob.g(L).show = get(handles.togglebutton_showGroup,'Value')

                glob.g(L).name = sprintf('g%d - cell %d',L,cid);
                showCellGroup(app, L)

                updateGroups(app)
                set(app.edit2,'Value','')
                edit2_function(app);
                set(handles.textCID,'Value',0);
                textCID_function(app)
                try
                    delete(glob.p.cell);
                end

            end
        end

        % Callback function
        function togglebutton_editPatch_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_editPatch (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_editPatch

            val = get(hObject,'Value');
            set(handles.uipanel_editPatch,'Visible',val);
        end

        % Value changed function: togglebutton_hidePath
        function togglebutton_hidePath_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_hidePath (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_hidePath

            global glob


            try
                set(glob.data.path.plotH,'Visible',...
                    ~get(glob.handles.togglebutton_hidePath,'Value'));
            end
        end

        % Value changed function: togglebutton_measurePath
        function togglebutton_measurePath_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_measurePath (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_measurePath

            global glob


            val = get(hObject,'Value');


            if val
                try
                    delete(glob.data.path.plotH);
                end
                deselectToggleUI(app, handles, hObject)
                glob.dcm = datacursormode(glob.fig);
                glob.dcm.UpdateFcn = @app.makePath;

                set(glob.dcm,'DisplayStyle','datatip',...
                    'SnapToDataVertex','off','Enable','on');
                plotPath(app)

            else
                rotate3d(glob.fig);
                delete(findall(glob.fig,'Type','hggroup'));

            end
        end

        % Callback function
        function togglebutton_pathProperties_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_pathProperties (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_pathProperties

            val = get(hObject,'Value');

            if val
                set(handles.uipanel_pathProperties,'Visible','on')
                updatePathPanel(app)
            else
                set(handles.uipanel_pathProperties,'Visible','off')
            end
        end

        % Value changed function: togglebutton_pickRef
        function togglebutton_pickRef_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_pickRef (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_pickRef


            global glob
            val = get(hObject,'Value');

            if val
                deselectToggleUI(app, handles, hObject)
                glob.dcm = datacursormode(glob.fig);
                glob.dcm.UpdateFcn = @app.cursorSelectRef;

                set(glob.dcm,'DisplayStyle','datatip',...
                    'SnapToDataVertex','off','Enable','on')
            else
                rotate3d(glob.fig)
                delete(findall(glob.fig,'Type','hggroup'))
                try
                    set(glob.highlight.oldPatch,...
                        'FaceColor', glob.highlight.oldColor,...
                        'FaceAlpha',glob.highlight.oldAlpha )
                end
            end
        end

        % Callback function
        function togglebutton_saveStuff_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_saveStuff (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_saveStuff

            val = get(hObject,'Value');
            set(handles.uipanel_saveStuff,'Visible',val)
        end

        % Callback function
        function togglebutton_showComPanel_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_showComPanel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_showComPanel

            val = get(hObject,'Value');
            if val
                set(handles.uipanel_Com ,'Visible','On')
            else
                set(handles.uipanel_Com , 'Visible','Off')
            end
        end

        % Value changed function: togglebutton_showGroup
        function togglebutton_showGroup_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_showGroup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_showGroup
            global glob

            G = app.renderGroups_popUp.Value;

            val = get(hObject,'Value');

            glob.g(G).show = val;

            if val
                set(hObject,'String','ON');
            else
                set(hObject,'String','OFF');
            end

            if ~isempty(glob.g(G).patch)
                for i = 1: length(glob.g(G).patch)
                    set(glob.g(G).patch(i),'Visible',val);
                    pause(.01)
                end
            elseif val
                showCellGroup(app, G)
            end
        end

        % Value changed function: togglebutton_synVisible
        function togglebutton_synVisible_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_synVisible (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_synVisible

            global glob

            val = get(hObject,'Value');
            G = app.popupmenu_synGroup.Value;

            try
                glob.syn.g(G).p.Visible = val;
            end

            if val
                set(hObject,'String','Visible')
                glob.syn.g(G).show = val;
            else
                set(hObject,'String','Hidden')
                glob.syn.g(G).show = val;
            end
        end

        % Button pushed function: viewXY
        function viewXY_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            global glob
            % hObject    handle to viewXY (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % %% Seek patches
            % vs = [];
            % pFields = fields(glob.p);
            % for f = 1:length(pFields)
            %     p = getfield(glob.p,pFields{f});
            %     for i = 1:length(p)
            %         vs = cat(1,vs,p(i).Vertices);
            %     end
            % end
            % glob.p.cell
            %
            % minV = min(vs,[],1);
            % maxV = max(vs,[],1);
            % midV = median(vs,1);
            %
            % view(glob.ax,[0 270])
            % glob.ax.CameraTarget = midV;

            view(glob.ax,[0 270])


        end

        % Button pushed function: viewXZ
        function viewXZ_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            global glob
            % hObject    handle to viewXZ (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % %% Seek patches
            % vs = [];
            % pFields = fields(glob.p);
            % for f = 1:length(pFields)
            %     p = getfield(glob.p,pFields{f});
            %     for i = 1:length(p)
            %         vs = cat(1,vs,p(i).Vertices);
            %     end
            % end
            % glob.p.cell
            %
            % minV = min(vs,[],1);
            % maxV = max(vs,[],1);
            % midV = median(vs,1);
            %
            % view(glob.ax,[0 0 ])
            % glob.ax.CameraTarget = midV;

            view(glob.ax,[0 0 ])
        end

        % Button pushed function: viewYZ
        function viewYZ_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            global glob
            % hObject    handle to viewYZ (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % %% Seek patches
            % vs = [];
            % pFields = fields(glob.p);
            % for f = 1:length(pFields)
            %     p = getfield(glob.p,pFields{f});
            %     for i = 1:length(p)
            %         vs = cat(1,vs,p(i).Vertices);
            %     end
            % end
            % glob.p.cell
            %
            % minV = min(vs,[],1);
            % maxV = max(vs,[],1);
            % midV = median(vs,1);
            %
            % view(glob.ax,[90 0])
            % glob.ax.CameraTarget = midV;

            view(glob.ax,[90 0])
        end

        % Button pushed function: ClearFieldButton
        function ClearFieldButtonPushed(app, event)
            global glob

            delete(findall(app.figure1,'Type','Patch'))
            glob.g = glob.defaultG;
            updateGroups(app)

            delete(findall(app.figure1,'Type','Scatter'))
            glob.syn.g = glob.syn.defaultG;
            synGroup2Panel(app);


        end

        % Value changed function: SetOriginButton
        function SetOriginButtonValueChanged(app, event)
            value = app.SetOriginButton.Value;
            % Value changed function: pushbutton_origin

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_origin (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global glob

            val = get(hObject,'Value');
            if val
                deselectToggleUI(app, handles, hObject)
                %try delete(glob.dcm), end
                glob.dcm = datacursormode(app.figure1);
                glob.dcm.UpdateFcn = @app.setOrigin;

                set(glob.dcm,'DisplayStyle','datatip',...
                    'SnapToDataVertex','off','Enable','on')
            else

                try glob.dcm.enable = 'off', end
                rotate3d(app.figure1)
                delete(findall(app.figure1,'Type','hggroup'))
            end

            pause(.01)
        end

        % Callback function
        function toggle_floatFig(app, event)
            global glob
            value = app.FloatingDisplayButton.Value;

            if value
                try set(glob.float.f,'Visible','on');
                    glob.ax = glob.float.ax;
                catch ME
                    floatingFigure(app)
                end
            else
                try set(glob.float.f,'Visible','off'); end
                glob.ax = app.mainAx;
            end
        end

        % Callback function
        function uipushtool2Clicked(app, event)
            global glob
            pan(glob.mainAx,'on')
        end

        % Callback function
        function ToggleToolOn(app, event)
            global glob
            pan(glob.mainAx,'on')
        end

        % Callback function
        function ToggleTool2On(app, event)
            pan(app.mainAx,'on');
            rotate3d(app.mainAx,'off');
            zoom(app.mainAx,'off');

        end

        % Callback function
        function ToggleTool2Off(app, event)
            pan(app.mainAx,'off');
        end

        % Callback function
        function ToggleTool3On(app, event)
            rotate3d(app.mainAx,'on');
            zoom(app.mainAx,'off');
            pan(app.mainAx,'off');

        end

        % Callback function
        function ToggleTool3Off(app, event)
            rotate3d(app.mainAx,'off');
        end

        % Callback function
        function ToggleTool4On(app, event)
            zoom(app.mainAx,'on');
            rotate3d(app.mainAx,'off');
            app.RotateToggle.Value = 0;
            pan(app.mainAx,'off');
            app.PanToggle.Value = 0;

        end

        % Callback function
        function ToggleTool4Off(app, event)
            zoom(app.mainAx,'off');
        end

        % Value changed function: PanToggle
        function PanToggleCallback(app, event)
            value = app.PanToggle.Value;
            if value
                pan(app.mainAx,'on');
                rotate3d(app.mainAx,'off');
                app.RotateToggle.Value = 0;
                zoom(app.mainAx,'off');
                app.ZoomToggle.Value = 0;
            else
                pan(app.mainAx,'off');
            end

        end

        % Value changed function: RotateToggle
        function rotateToggle_Callback(app, event)
            value = app.RotateToggle.Value;
            if value
                rotate3d(app.mainAx,'on');
                zoom(app.mainAx,'off');
                app.ZoomToggle.Value = 0;
                pan(app.mainAx,'off');
                app.PanToggle.Value = 0;
            else
                rotate3d(app.mainAx,'off')
            end
        end

        % Value changed function: ZoomToggle
        function zoomToggle_Callback(app, event)
            value = app.ZoomToggle.Value;
            if value
                zoom(app.mainAx,'on');
                rotate3d(app.mainAx,'off');
                app.RotateToggle.Value = 0;
                pan(app.mainAx,'off');
                app.PanToggle.Value = 0;
            else
                zoom(app.mainAx,'off')
            end
        end

        % Menu selected function: CelldatafetchwindowMenu
        function menuGetCellDat(app, event)
            datLinkFig_App
        end

        % Menu selected function: RefreshcelldataMenu
        function refreshCellDat(app, event)
            app.textOut.Value = 'Making dat file from Google document';
            pause(.01)


            global glob

            activeVolume = glob.vol.activeName;
            if strcmp(activeVolume,'Main')
                app.textOut.Value = 'Cannot import data into Main database. Choose Volume before refresh';
                flashTextOut(app)
                pause(.01)
            else

                glob.NA.export.volume = activeVolume;


                glob.NA.export.datDir = [glob.dir.Volumes activeVolume '\Merge\'];
                %% load sheet data
                try
                    load([glob.NA.export.datDir 'dat.mat'])
                    glob.NA.export.googleLinks = dat.googleLinks;

                    https =  'https://docs.google.com/spreadsheets/d/';
                    datPath = sprintf('%s%s/edit#gid=%s',https,dat.googleLinks.datSheet,dat.googleLinks.datGID);
                    aliasPath = sprintf('%s%s/edit#gid=%s',https,dat.googleLinks.datSheet,dat.googleLinks.aliasGID);

                    glob.NA.export.aliasPath = aliasPath;
                    glob.NA.export.datPath = datPath;

                catch err
                    app.textOut.Value = 'No dat.mat found';
                    flashTextOut(app)
                end

                [dat.googleLinks.datSheet dat.googleLinks.datGID]  = parseSheetAddress(app, datPath);
                [a  dat.googleLinks.aliasGID]  = parseSheetAddress(app, aliasPath);

                dat = parseGoogleDat(dat)
                save([glob.NA.export.datDir 'dat.mat'],'dat')

                app.textOut.Value = 'Done making dat.mat';
                pause(.01)

                %%Make tis

                app.textOut.Value = 'Making tis.mat';
                pause(.01)

                load([glob.NA.export.datDir 'dat.mat'])
                load([glob.NA.export.datDir 'obI.mat'])
                binder.obI = obI;
                binder.dat = dat;

                tis = makeTis(binder);
                fvDir = [glob.dir.Volumes glob.NA.export.volume '\Analysis\fvLibrary\'];
                save([fvDir 'tis.mat'],'tis');
                app.textOut.Value = 'Finished making tis.mat. Updating CellNav.';


                oldType = glob.typeStrings{app.popupCellType.Value};
                strs = app.subType_popupMenue.Items;
                oldSubType= strs{app.subType_popupMenue.Value};

                updateCellNavGlob
                app.popupCellType.Value = 1;

                app.popupCellType.Items = glob.typeStrings;
                app.popupCellType.ItemsData = 1:length(glob.typeStrings);
                targ = find(strcmp(glob.typeStrings,oldType),1);
                if ~isempty(targ)
                    app.popupCellType.Value = targ;
                    popupCellType_Function(app);
                    strs = app.subType_popupMenue.Items;
                    targ2 = find(strcmp(strs,oldSubType),1);
                    if ~isempty(targ2)
                        app.subType_popupMenue.Value = targ2;
                        subType_popupMenue_Function(app)
                    end
                else

                    app.popupCellType.Value = 1;
                    popupCellType_Function(app);
                end


                app.textOut.Value = 'Finished updating CellNav.  Reselection of volume may be necessary.';

            end
        end

        % Drop down opening function: popupCellType
        function popupCellTypeDropDownOpening(app, event)

        end

        % Menu selected function: CellAnalysisSkeletonMenu
        function runSkeletonizeCells(app, event)
            skeletonizeCells_App
        end

        % Callback function
        function togglebutton_show(app, event)
            value = app.togglebutton_pathProperties.Value;

        end

        % Button pushed function: CloseButton
        function closeCellNaveFig(app, event)
            global glob
            try close(glob.fig),end
            delete(app)
        end

        % Value changed function: scaleBar_ScaleBarButton
        function toggle_scaleBar(app, event)
            value = app.scaleBar_ScaleBarButton.Value;
            global glob

            if value
                drawScaleBar(app)
            else
                try set(glob.p.scaleBar,'Visible','off')
                end
            end


        end

        % Callback function
        function scaleBarSlideY(app, event)
            drawScaleBar(app)
        end

        % Callback function
        function scaleBarSldieY(app, event)
            changingValue = event.Value;

        end

        % Callback function: scaleBar_DimDropDown, scaleBar_DimDropDown, 
        % ...and 8 other components
        function scaleBarSlide(app, event)
            drawScaleBar(app)

        end

        % Value changed function: ClickPosButton
        function scaleBar_clickPos(app, event)
            val = app.ClickPosButton.Value;
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            global glob
            if val
                deselectToggleUI(app, handles, hObject)
                glob.dcm = datacursormode(glob.fig);
                glob.dcm.UpdateFcn = @app.scaleBar_click;

                set(glob.dcm,'DisplayStyle','datatip',...
                    'SnapToDataVertex','off','Enable','on')
            else
                rotate3d(glob.fig)
                delete(findall(glob.fig,'Type','hggroup'))
            end
        end

        % Button pushed function: RegistertoViewAngleButton
        function scaleBar_registerAngle(app, event)

            global glob


            y = app.scaleBar_YSlider.Value;
            x = app.scaleBar_XSlider.Value;
            z = app.scaleBar_ZSlider.Value;
            w = app.scaleBar_WidthEditField.Value;
            h = app.scaleBar_HeightEditField.Value;
            dim = app.scaleBar_DimDropDown.Value;

            %%make corners
            y2 = y+h;
            x2 = x+h;
            z2 = z+h;
            if strcmp(dim,'Y')
                y2 = y+w;
            elseif strcmp(dim,'X')
                x2 = x+w;
            else
                z2 = z+w;
            end

            %% make verticies
            vert = [y x z; y2 x z; y2 x2 z; y x2 z; ...
                y x z2; y2 x z2; y2 x2 z2; y x2 z2];
            sb.faces = [1 2 3 4; 5 6 7 8; 1 2 6 5; 3 4 8 7; 2 3 7 6; 1 4 8 5];

            %%Transform
            try glob.p.scaleBar.delete
            end
            [az el] = view(glob.ax)

            %%transform
            ym = (y+y2)/2;
            xm = (x+x2)/2;
            zm = (z+z2)/2;
            vert = vert - repmat([ym xm zm],[size(vert,1) 1]);

            if strcmp(dim,'Y')
                T = viewmtx(az,0);
                switchDim = [1 3 2]; %works for side view
            elseif strcmp(dim,'X')
                T = viewmtx(az,el);
                switchDim = [3 1 2]; %works for side view
            elseif strcmp(dim,'Z')
                T = viewmtx(0,el);
                switchDim = [1 3 2]; %works for side view
            end


            vert4d = [vert(:,switchDim) ones(size(vert,1),1)]';
            vert = T*vert4d;
            vert = vert(switchDim,:)';
            vert = vert + repmat([ym xm zm],[size(vert,1) 1]);

            sb.vertices = vert;
            glob.p.scaleBar = patch(glob.ax,sb,'facecolor','w','edgecolor','w','linestyle','none',...
                'SpecularStrength',0,'DiffuseStrength',1);
            drawnow


            %%


        end

        % Menu selected function: DisplayMenu
        function ScriptCustomDisplay(app, event)

            global glob

            cnDir = glob.cellNav.pwd;
            scriptDir = [cnDir '\customScripts\Display\'];
            [TFN TPN] = uigetfile(scriptDir);
            if ~isempty(TFN)
                app.textOut.Value = sprintf('running %s',TFN); drawnow
                run([TPN TFN])
                app.textOut.Value = sprintf('finished running %s',TFN); drawnow

            end


        end

        % Button pushed function: AllButton
        function clearAll_button(app, event)
            clearAllGroups(app)
        end

        % Menu selected function: ProjectsMenu
        function runProjectScriptsMenu(app, event)
            global glob

            cnDir = glob.cellNav.pwd;
            scriptDir = [cnDir '\customScripts\Projects\'];
            [TFN TPN] = uigetfile(scriptDir);
            if ~isempty(TFN)
                app.textOut.Value = sprintf('running %s',TFN); drawnow
                run([TPN TFN])
                app.textOut.Value = sprintf('finished running %s',TFN); drawnow

            end
        end

        % Menu selected function: ClassificationMenu
        function runClassificationScripts(app, event)
            global glob

            cnDir = glob.cellNav.pwd;
            scriptDir = [cnDir '\customScripts\Classification\'];
            [TFN TPN] = uigetfile(scriptDir);
            if ~isempty(TFN)
                app.textOut.Value = sprintf('running %s',TFN); drawnow
                run([TPN TFN])
                app.textOut.Value = sprintf('finished running %s',TFN); drawnow

            end
        end

        % Value changed function: popupmenu_preClassSynapse
        function popPreClassSynapse(app, event)
            value = app.popupmenu_preClassSynapse.Value;


            global glob

            val = app.popupmenu_preClassSynapse.Value;
            strs = app.popupmenu_preClassSynapse.Items;
            str = strs{val+1};

            G = app.popupmenu_synGroup.Value;
            glob.syn.g(G).preType = str;
            glob.syn.g(G).preTypeID = val;

            drawSyn(app)


        end

        % Value changed function: popupmenu_postClassSynapse
        function popPostClassSynapse(app, event)
            global glob

            val = app.popupmenu_postClassSynapse.Value;
            strs = app.popupmenu_postClassSynapse.Items;
            str = strs{val+1};

            G = app.popupmenu_synGroup.Value;
            glob.syn.g(G).postType = str;
            glob.syn.g(G).postTypeID = val;

            drawSyn(app)
        end

        % Menu selected function: PublicationMenu
        function PublicationMenuCallback(app, event)
            global glob

            cnDir = glob.cellNav.pwd;
            scriptDir = [cnDir '\customScripts\Publication\'];
            [TFN TPN] = uigetfile(scriptDir);
            if ~isempty(TFN)
                app.textOut.Value = sprintf('running %s',TFN); drawnow
                run([TPN TFN])
                app.textOut.Value = sprintf('finished running %s',TFN); drawnow

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'cellNavGuide';
            app.figure1.Tag = 'figure1';
            app.manageLibrary = uimenu(app.figure1);
            app.manageLibrary.Text = 'Manage Library    ';
            app.manageLibrary.Tag = 'manageLibrary';
            app.RefreshcelldataMenu = uimenu(app.manageLibrary);
            app.RefreshcelldataMenu.MenuSelectedFcn = createCallbackFcn(app, @refreshCellDat, true);
            app.RefreshcelldataMenu.Text = 'Refresh cell data';
            app.menuImport = uimenu(app.manageLibrary);
            app.menuImport.MenuSelectedFcn = createCallbackFcn(app, @menuImport_Callback, true);
            app.menuImport.Text = 'Import from VAST';
            app.menuImport.Tag = 'menuImport';
            app.CelldatafetchwindowMenu = uimenu(app.manageLibrary);
            app.CelldatafetchwindowMenu.MenuSelectedFcn = createCallbackFcn(app, @menuGetCellDat, true);
            app.CelldatafetchwindowMenu.Text = 'Cell data fetch window';
            app.menuTransformVolume = uimenu(app.manageLibrary);
            app.menuTransformVolume.MenuSelectedFcn = createCallbackFcn(app, @menuTransformVolume_Callback, true);
            app.menuTransformVolume.Text = 'Transform Volume';
            app.menuTransformVolume.Tag = 'menuTransformVolume';
            app.menu_importSWC = uimenu(app.manageLibrary);
            app.menu_importSWC.MenuSelectedFcn = createCallbackFcn(app, @menu_importSWC_Callback, true);
            app.menu_importSWC.Checked = 'on';
            app.menu_importSWC.Text = 'Import from SWC folder';
            app.menu_importSWC.Tag = 'menu_importSWC';
            app.menueAnalyze = uimenu(app.figure1);
            app.menueAnalyze.Text = 'Analyze    ';
            app.menueAnalyze.Tag = 'menueAnalyze';
            app.CellAnalysisSkeletonMenu = uimenu(app.menueAnalyze);
            app.CellAnalysisSkeletonMenu.MenuSelectedFcn = createCallbackFcn(app, @runSkeletonizeCells, true);
            app.CellAnalysisSkeletonMenu.Text = 'Cell Analysis/Skeleton';
            app.runEditSkeleton = uimenu(app.menueAnalyze);
            app.runEditSkeleton.MenuSelectedFcn = createCallbackFcn(app, @runEditSkeleton_Callback, true);
            app.runEditSkeleton.Text = 'Edit Skeleton';
            app.runEditSkeleton.Tag = 'runEditSkeleton';
            app.menuGraphPointToPoint = uimenu(app.menueAnalyze);
            app.menuGraphPointToPoint.MenuSelectedFcn = createCallbackFcn(app, @menuGraphPointToPoint_Callback, true);
            app.menuGraphPointToPoint.Text = 'Graph Point To Point';
            app.menuGraphPointToPoint.Tag = 'menuGraphPointToPoint';
            app.menuCellTable = uimenu(app.menueAnalyze);
            app.menuCellTable.MenuSelectedFcn = createCallbackFcn(app, @menuCellTable_Callback, true);
            app.menuCellTable.Text = 'Cell Table';
            app.menuCellTable.Tag = 'menuCellTable';
            app.menuDataPanel = uimenu(app.menueAnalyze);
            app.menuDataPanel.MenuSelectedFcn = createCallbackFcn(app, @menuDataPanel_Callback, true);
            app.menuDataPanel.Text = 'Data Panel';
            app.menuDataPanel.Tag = 'menuDataPanel';
            app.menu_FindAppositions = uimenu(app.menueAnalyze);
            app.menu_FindAppositions.MenuSelectedFcn = createCallbackFcn(app, @menu_FindAppositions_Callback, true);
            app.menu_FindAppositions.Text = 'Find Appositions';
            app.menu_FindAppositions.Tag = 'menu_FindAppositions';
            app.menuCustomize = uimenu(app.figure1);
            app.menuCustomize.Text = 'Customize Groups    ';
            app.menuCustomize.Tag = 'menuCustomize';
            app.menuObjectGroups = uimenu(app.menuCustomize);
            app.menuObjectGroups.MenuSelectedFcn = createCallbackFcn(app, @menuObjectGroups_Callback, true);
            app.menuObjectGroups.Text = 'Build Group From Objects';
            app.menuObjectGroups.Tag = 'menuObjectGroups';
            app.menuCustomGroups = uimenu(app.menuCustomize);
            app.menuCustomGroups.MenuSelectedFcn = createCallbackFcn(app, @menuCustomGroups_Callback, true);
            app.menuCustomGroups.Text = 'Merge/Subtract Groups';
            app.menuCustomGroups.Tag = 'menuCustomGroups';
            app.customSynGroup = uimenu(app.menuCustomize);
            app.customSynGroup.MenuSelectedFcn = createCallbackFcn(app, @customSynGroup_Callback, true);
            app.customSynGroup.Text = 'Merge/Subtract Synapse Groups';
            app.customSynGroup.Tag = 'customSynGroup';
            app.menuAnnotate = uimenu(app.figure1);
            app.menuAnnotate.Separator = 'on';
            app.menuAnnotate.Text = 'Annotate    ';
            app.menuAnnotate.Tag = 'menuAnnotate';
            app.menuePointList = uimenu(app.menuAnnotate);
            app.menuePointList.MenuSelectedFcn = createCallbackFcn(app, @menuePointList_Callback, true);
            app.menuePointList.Checked = 'on';
            app.menuePointList.Text = 'Point List';
            app.menuePointList.Tag = 'menuePointList';
            app.menuMakePath = uimenu(app.menuAnnotate);
            app.menuMakePath.MenuSelectedFcn = createCallbackFcn(app, @menuMakePath_Callback, true);
            app.menuMakePath.Text = 'Make Path';
            app.menuMakePath.Tag = 'menuMakePath';
            app.RunScriptsMenu = uimenu(app.figure1);
            app.RunScriptsMenu.Text = 'RunScripts';
            app.ProjectsMenu = uimenu(app.RunScriptsMenu);
            app.ProjectsMenu.MenuSelectedFcn = createCallbackFcn(app, @runProjectScriptsMenu, true);
            app.ProjectsMenu.Text = 'Projects';
            app.DisplayMenu = uimenu(app.RunScriptsMenu);
            app.DisplayMenu.MenuSelectedFcn = createCallbackFcn(app, @ScriptCustomDisplay, true);
            app.DisplayMenu.Text = 'Display';
            app.ClassificationMenu = uimenu(app.RunScriptsMenu);
            app.ClassificationMenu.MenuSelectedFcn = createCallbackFcn(app, @runClassificationScripts, true);
            app.ClassificationMenu.Text = 'Classification';
            app.PublicationMenu = uimenu(app.RunScriptsMenu);
            app.PublicationMenu.MenuSelectedFcn = createCallbackFcn(app, @PublicationMenuCallback, true);
            app.PublicationMenu.Text = 'Menu';
            app.mainAx = uiaxes(app.figure1);
            app.mainAx.XLim = [107.822037795516 178.543639760456];
            app.mainAx.YLim = [112.350262826547 183.071864791487];
            app.mainAx.ZLim = [-20.0603557517311 50.6612462132089];
            app.mainAx.Tag = 'mainAx';
            app.mainAx.Visible = 'off';
            app.panelView = uipanel(app.figure1);
            app.panelView.Title = 'View';
            app.panelView.Tag = 'panelView';
            app.popupmenu_OtherObjects = uidropdown(app.panelView);
            app.popupmenu_OtherObjects.Items = {'IPL Borders', 'Scale Bar', 'All Bipolars'};
            app.popupmenu_OtherObjects.ItemsData = [1 2 3];
            app.popupmenu_OtherObjects.ValueChangedFcn = createCallbackFcn(app, @popupmenu_OtherObjects_Callback, true);
            app.popupmenu_OtherObjects.Tag = 'popupmenu_OtherObjects';
            app.popupmenu_OtherObjects.Value = 1;
            app.viewXY = uibutton(app.panelView, 'push');
            app.viewXY.ButtonPushedFcn = createCallbackFcn(app, @viewXY_Callback, true);
            app.viewXY.Tag = 'viewXY';
            app.viewXY.Text = 'View XY';
            app.viewXZ = uibutton(app.panelView, 'push');
            app.viewXZ.ButtonPushedFcn = createCallbackFcn(app, @viewXZ_Callback, true);
            app.viewXZ.Tag = 'viewXZ';
            app.viewXZ.Text = 'View XZ';
            app.viewYZ = uibutton(app.panelView, 'push');
            app.viewYZ.ButtonPushedFcn = createCallbackFcn(app, @viewYZ_Callback, true);
            app.viewYZ.Tag = 'viewYZ';
            app.viewYZ.Text = 'View YZ';
            app.raiseLight = uibutton(app.panelView, 'push');
            app.raiseLight.ButtonPushedFcn = createCallbackFcn(app, @raiseLight_Callback, true);
            app.raiseLight.Tag = 'raiseLight';
            app.raiseLight.Text = 'Raise Light';
            app.pushbutton_resetZoom = uibutton(app.panelView, 'state');
            app.pushbutton_resetZoom.ValueChangedFcn = createCallbackFcn(app, @pushbutton_resetZoom_Callback, true);
            app.pushbutton_resetZoom.Tag = 'pushbutton_resetZoom';
            app.pushbutton_resetZoom.Text = 'Auto Zoom';
            app.pushbutton_blendPatches = uibutton(app.panelView, 'push');
            app.pushbutton_blendPatches.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_blendPatches_Callback, true);
            app.pushbutton_blendPatches.Tag = 'pushbutton_blendPatches';
            app.pushbutton_blendPatches.Text = 'Blend Patches';
            app.text_showRef = uilabel(app.panelView);
            app.text_showRef.Tag = 'text_showRef';
            app.text_showRef.Text = 'Show';
            app.ClearFieldButton = uibutton(app.panelView, 'push');
            app.ClearFieldButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFieldButtonPushed, true);
            app.ClearFieldButton.Text = 'Clear Field';
            app.SetOriginButton = uibutton(app.panelView, 'state');
            app.SetOriginButton.ValueChangedFcn = createCallbackFcn(app, @SetOriginButtonValueChanged, true);
            app.SetOriginButton.Tag = 'toggle_setOrigin';
            app.SetOriginButton.Text = 'Set Origin';
            app.panelConnections = uipanel(app.figure1);
            app.panelConnections.Title = 'Cell Properties';
            app.panelConnections.Tag = 'panelConnections';
            app.pushPreCell = uibutton(app.panelConnections, 'state');
            app.pushPreCell.ValueChangedFcn = createCallbackFcn(app, @pushPreCell_Callback, true);
            app.pushPreCell.Tag = 'pushPreCell';
            app.pushPreCell.Text = 'Pre Cells';
            app.pushPostCells = uibutton(app.panelConnections, 'state');
            app.pushPostCells.ValueChangedFcn = createCallbackFcn(app, @pushPostCells_Callback, true);
            app.pushPostCells.Tag = 'pushPostCells';
            app.pushPostCells.Text = 'Post Cells';
            app.listbox_preCol = uidropdown(app.panelConnections);
            app.listbox_preCol.Items = {'rand', 'rainbow', 'red', 'green', 'blue', 'yellow', 'magenta', 'cyan', 'white', 'grey'};
            app.listbox_preCol.ItemsData = [1 2 3 4 5 6 7 8 9 10];
            app.listbox_preCol.ValueChangedFcn = createCallbackFcn(app, @listbox_preCol_Callback, true);
            app.listbox_preCol.Tag = 'listbox_preCol';
            app.listbox_preCol.Value = 2;
            app.listbox3 = uidropdown(app.panelConnections);
            app.listbox3.Items = {'rand', 'rainbow', 'red', 'green', 'blue', 'yellow', 'magenta', 'cyan', 'white', 'grey'};
            app.listbox3.ItemsData = [1 2 3 4 5 6 7 8 9 10];
            app.listbox3.ValueChangedFcn = createCallbackFcn(app, @listbox3_Callback, true);
            app.listbox3.Tag = 'listbox3';
            app.listbox3.Value = 1;
            app.pushbutton_preGroup = uibutton(app.panelConnections, 'push');
            app.pushbutton_preGroup.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_preGroup_Callback, true);
            app.pushbutton_preGroup.Tag = 'pushbutton_preGroup';
            app.pushbutton_preGroup.Text = 'G';
            app.pushbutton_postGroup = uibutton(app.panelConnections, 'push');
            app.pushbutton_postGroup.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_postGroup_Callback, true);
            app.pushbutton_postGroup.Tag = 'pushbutton_postGroup';
            app.pushbutton_postGroup.Text = 'G';
            app.pushPreSyn = uibutton(app.panelConnections, 'state');
            app.pushPreSyn.ValueChangedFcn = createCallbackFcn(app, @pushPreSyn_Callback, true);
            app.pushPreSyn.Tag = 'pushPreSyn';
            app.pushPreSyn.Text = 'Pre Syn';
            app.pushPostSyn = uibutton(app.panelConnections, 'state');
            app.pushPostSyn.ValueChangedFcn = createCallbackFcn(app, @pushPostSyn_Callback, true);
            app.pushPostSyn.Tag = 'pushPostSyn';
            app.pushPostSyn.Text = 'Post Syn';
            app.pushShrinkMarker = uibutton(app.panelConnections, 'push');
            app.pushShrinkMarker.ButtonPushedFcn = createCallbackFcn(app, @pushShrinkMarker_Callback, true);
            app.pushShrinkMarker.Tag = 'pushShrinkMarker';
            app.pushShrinkMarker.Text = '(-)';
            app.pushGrowMarker = uibutton(app.panelConnections, 'push');
            app.pushGrowMarker.ButtonPushedFcn = createCallbackFcn(app, @pushGrowMarker_Callback, true);
            app.pushGrowMarker.Tag = 'pushGrowMarker';
            app.pushGrowMarker.Text = '(+)';
            app.toggleRadius = uibutton(app.panelConnections, 'state');
            app.toggleRadius.ValueChangedFcn = createCallbackFcn(app, @toggleRadius_Callback, true);
            app.toggleRadius.Tag = 'toggleRadius';
            app.toggleRadius.Text = 'Radius';
            app.toggleSkeleton = uibutton(app.panelConnections, 'state');
            app.toggleSkeleton.ValueChangedFcn = createCallbackFcn(app, @toggleSkeleton_Callback, true);
            app.toggleSkeleton.Tag = 'toggleSkeleton';
            app.toggleSkeleton.Text = 'Skeleton';
            app.pushbutton_makeTable = uibutton(app.panelConnections, 'push');
            app.pushbutton_makeTable.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_makeTable_Callback, true);
            app.pushbutton_makeTable.Tag = 'pushbutton_makeTable';
            app.pushbutton_makeTable.Text = 'Table';
            app.panelGetPos = uipanel(app.figure1);
            app.panelGetPos.Title = 'Click Data';
            app.panelGetPos.Tag = 'panelGetPos';
            app.pushDataTip = uibutton(app.panelGetPos, 'state');
            app.pushDataTip.ValueChangedFcn = createCallbackFcn(app, @pushDataTip_Callback, true);
            app.pushDataTip.Tag = 'pushDataTip';
            app.pushDataTip.Text = 'Gety XYZ';
            app.toggle_highlightCell = uibutton(app.panelGetPos, 'state');
            app.toggle_highlightCell.ValueChangedFcn = createCallbackFcn(app, @toggle_highlightCell_Callback, true);
            app.toggle_highlightCell.Tag = 'toggle_highlightCell';
            app.toggle_highlightCell.Text = 'Highlight Cell';
            app.panelGroup = uipanel(app.figure1);
            app.panelGroup.Title = 'Groups';
            app.panelGroup.Tag = 'panelGroup';
            app.showSub_toggle = uibutton(app.panelGroup, 'state');
            app.showSub_toggle.ValueChangedFcn = createCallbackFcn(app, @showSub_toggle_Callback, true);
            app.showSub_toggle.Tag = 'showSub_toggle';
            app.showSub_toggle.Text = 'Type';
            app.subColor_popUp = uidropdown(app.panelGroup);
            app.subColor_popUp.Items = {'rand', 'rainbow', 'red', 'green', 'blue', 'yellow', 'magenta', 'cyan', 'white', 'grey'};
            app.subColor_popUp.ItemsData = [1 2 3 4 5 6 7 8 9 10];
            app.subColor_popUp.ValueChangedFcn = createCallbackFcn(app, @subColor_popUp_Callback, true);
            app.subColor_popUp.Tag = 'subColor_popUp';
            app.subColor_popUp.Value = 1;
            app.renderGroups_popUp = uidropdown(app.panelGroup);
            app.renderGroups_popUp.Items = {};
            app.renderGroups_popUp.ValueChangedFcn = createCallbackFcn(app, @renderGroups_popUp_Callback, true);
            app.renderGroups_popUp.Tag = 'renderGroups_popUp';
            app.renderGroups_popUp.Value = {};
            app.addRef_toggleButton = uibutton(app.panelGroup, 'state');
            app.addRef_toggleButton.ValueChangedFcn = createCallbackFcn(app, @addRef_toggleButton_Callback, true);
            app.addRef_toggleButton.Tag = 'addRef_toggleButton';
            app.addRef_toggleButton.Text = 'Ref';
            app.deleteGroup = uibutton(app.panelGroup, 'state');
            app.deleteGroup.ValueChangedFcn = createCallbackFcn(app, @deleteGroup_Callback, true);
            app.deleteGroup.Tag = 'deleteGroup';
            app.deleteGroup.Text = 'Del';
            app.subAlpha_popUp = uidropdown(app.panelGroup);
            app.subAlpha_popUp.Items = {'0', '.1', '.2', '.3', '.4', '.5', '.5', '.6', '.7', '.8', '.9', '1'};
            app.subAlpha_popUp.ItemsData = [1 2 3 4 5 6 7 8 9 10 11];
            app.subAlpha_popUp.ValueChangedFcn = createCallbackFcn(app, @subAlpha_popUp_Callback, true);
            app.subAlpha_popUp.Tag = 'subAlpha_popUp';
            app.subAlpha_popUp.Value = 8;
            app.togglebutton_cell2group = uibutton(app.panelGroup, 'state');
            app.togglebutton_cell2group.ValueChangedFcn = createCallbackFcn(app, @togglebutton_cell2group_Callback, true);
            app.togglebutton_cell2group.Tag = 'togglebutton_cell2group';
            app.togglebutton_cell2group.Text = 'Cell';
            app.togglebutton_showGroup = uibutton(app.panelGroup, 'state');
            app.togglebutton_showGroup.ValueChangedFcn = createCallbackFcn(app, @togglebutton_showGroup_Callback, true);
            app.togglebutton_showGroup.Tag = 'togglebutton_showGroup';
            app.togglebutton_showGroup.Text = 'ON';
            app.togglebutton_showGroup.Value = true;
            app.uipanel_SelectedCell = uipanel(app.figure1);
            app.uipanel_SelectedCell.Title = 'Select Cells';
            app.uipanel_SelectedCell.Tag = 'uipanel_SelectedCell';
            app.popupCellType = uidropdown(app.uipanel_SelectedCell);
            app.popupCellType.Items = {'all', 'unassigned', 'rgc', 'unk', 'bpc', 'amc'};
            app.popupCellType.ItemsData = [1 2 3 4 5 6];
            app.popupCellType.DropDownOpeningFcn = createCallbackFcn(app, @popupCellTypeDropDownOpening, true);
            app.popupCellType.ValueChangedFcn = createCallbackFcn(app, @popupCellType_Callback, true);
            app.popupCellType.Tag = 'popupCellType';
            app.popupCellType.Value = 1;
            app.listCIDs = uilistbox(app.uipanel_SelectedCell);
            app.listCIDs.Items = {'none'};
            app.listCIDs.ItemsData = 1;
            app.listCIDs.ValueChangedFcn = createCallbackFcn(app, @listCIDs_Callback, true);
            app.listCIDs.Tag = 'listCIDs';
            app.listCIDs.Value = 1;
            app.nextCell = uibutton(app.uipanel_SelectedCell, 'push');
            app.nextCell.ButtonPushedFcn = createCallbackFcn(app, @nextCell_Callback, true);
            app.nextCell.Tag = 'nextCell';
            app.nextCell.Text = '>>>';
            app.textCellType = uilabel(app.uipanel_SelectedCell);
            app.textCellType.Tag = 'textCellType';
            app.textCellType.Text = 'Cell type';
            app.subType_popupMenue = uidropdown(app.uipanel_SelectedCell);
            app.subType_popupMenue.Items = {'all', 'vgc'};
            app.subType_popupMenue.ItemsData = [1 2];
            app.subType_popupMenue.ValueChangedFcn = createCallbackFcn(app, @subType_popupMenue_Callback, true);
            app.subType_popupMenue.Tag = 'subType_popupMenue';
            app.subType_popupMenue.Value = 1;
            app.text8 = uilabel(app.uipanel_SelectedCell);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Sub type';
            app.prevCell = uibutton(app.uipanel_SelectedCell, 'push');
            app.prevCell.ButtonPushedFcn = createCallbackFcn(app, @prevCell_Callback, true);
            app.prevCell.Tag = 'prevCell';
            app.prevCell.Text = '<<<';
            app.edit1 = uieditfield(app.uipanel_SelectedCell, 'text');
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1_Callback, true);
            app.edit1.Tag = 'edit1';
            app.edit2 = uieditfield(app.uipanel_SelectedCell, 'text');
            app.edit2.ValueChangedFcn = createCallbackFcn(app, @edit2_Callback, true);
            app.edit2.Tag = 'edit2';
            app.textRefCell = uibutton(app.uipanel_SelectedCell, 'state');
            app.textRefCell.ValueChangedFcn = createCallbackFcn(app, @textRefCell_Callback, true);
            app.textRefCell.Tag = 'textRefCell';
            app.textRefCell.Text = 'Ref Cells';
            app.toggle_selectCell = uibutton(app.uipanel_SelectedCell, 'state');
            app.toggle_selectCell.ValueChangedFcn = createCallbackFcn(app, @toggle_selectCell_Callback, true);
            app.toggle_selectCell.Tag = 'toggle_selectCell';
            app.toggle_selectCell.Text = 'Click Cell';
            app.textCID = uibutton(app.uipanel_SelectedCell, 'state');
            app.textCID.ValueChangedFcn = createCallbackFcn(app, @textCID_Callback, true);
            app.textCID.Tag = 'textCID';
            app.textCID.Text = 'Cell ID';
            app.textCID.Value = true;
            app.togglebutton_pickRef = uibutton(app.uipanel_SelectedCell, 'state');
            app.togglebutton_pickRef.ValueChangedFcn = createCallbackFcn(app, @togglebutton_pickRef_Callback, true);
            app.togglebutton_pickRef.Tag = 'togglebutton_pickRef';
            app.togglebutton_pickRef.Text = 'Click Cell';
            app.uipanel_textOut = uipanel(app.figure1);
            app.uipanel_textOut.Title = 'Text Out';
            app.uipanel_textOut.Tag = 'uipanel_textOut';
            app.textOut = uitextarea(app.uipanel_textOut);
            app.textOut.Tag = 'textOut';
            app.uipanel19 = uipanel(app.figure1);
            app.uipanel19.Title = 'Select Volume';
            app.uipanel19.Tag = 'uipanel19';
            app.popupmenu_sourceVolume = uidropdown(app.uipanel19);
            app.popupmenu_sourceVolume.Items = {''};
            app.popupmenu_sourceVolume.ValueChangedFcn = createCallbackFcn(app, @popupmenu_sourceVolume_Callback, true);
            app.popupmenu_sourceVolume.Tag = 'popupmenu_sourceVolume';
            app.popupmenu_sourceVolume.Value = '';
            app.InteractivityPanel = uipanel(app.figure1);
            app.InteractivityPanel.Title = 'Interactivity';
            app.PanToggle = uibutton(app.InteractivityPanel, 'state');
            app.PanToggle.ValueChangedFcn = createCallbackFcn(app, @PanToggleCallback, true);
            app.PanToggle.Tag = 'PanToggle';
            app.PanToggle.Icon = 'pan.png';
            app.PanToggle.Text = '';
            app.RotateToggle = uibutton(app.InteractivityPanel, 'state');
            app.RotateToggle.ValueChangedFcn = createCallbackFcn(app, @rotateToggle_Callback, true);
            app.RotateToggle.Tag = 'RotateToggle';
            app.RotateToggle.Icon = 'rotate.png';
            app.RotateToggle.Text = '';
            app.ZoomToggle = uibutton(app.InteractivityPanel, 'state');
            app.ZoomToggle.ValueChangedFcn = createCallbackFcn(app, @zoomToggle_Callback, true);
            app.ZoomToggle.Tag = 'ZoomToggle';
            app.ZoomToggle.Icon = 'zoom.png';
            app.ZoomToggle.Text = '';
            app.TabGroup = uitabgroup(app.figure1);
            app.SynapsesTab = uitab(app.TabGroup);
            app.SynapsesTab.Title = 'Synapses';
            app.popupmenu_synSynapseType = uidropdown(app.SynapsesTab);
            app.popupmenu_synSynapseType.Items = {'all', 'conventional', 'ribbon', 'junction'};
            app.popupmenu_synSynapseType.ItemsData = {'1', '2', '3', '4'};
            app.popupmenu_synSynapseType.ValueChangedFcn = createCallbackFcn(app, @popupmenu_synSynapseType_Callback, true);
            app.popupmenu_synSynapseType.Tag = 'popupmenu_synSynapseType';
            app.popupmenu_synSynapseType.Value = '1';
            app.text_preText = uilabel(app.SynapsesTab);
            app.text_preText.Tag = 'text_preText';
            app.text_preText.Text = 'Pre';
            app.text_postText = uilabel(app.SynapsesTab);
            app.text_postText.Tag = 'text_postText';
            app.text_postText.Text = 'Post';
            app.text_synSynapseType = uilabel(app.SynapsesTab);
            app.text_synSynapseType.Tag = 'text_synSynapseType';
            app.text_synSynapseType.Text = 'Synapse Type';
            app.popupmenu_synMarker = uidropdown(app.SynapsesTab);
            app.popupmenu_synMarker.Items = {'o', '+', '*', '.', 'x', 'square', 'diamond', '^', 'V', '<', '>', 'pentagram', 'hexagram', 'none'};
            app.popupmenu_synMarker.ItemsData = [1 2 3 4 5 6 7 8 9 10 11 12 13 14];
            app.popupmenu_synMarker.ValueChangedFcn = createCallbackFcn(app, @popupmenu_synMarker_Callback, true);
            app.popupmenu_synMarker.Tag = 'popupmenu_synMarker';
            app.popupmenu_synMarker.Value = 1;
            app.text_synMarker = uilabel(app.SynapsesTab);
            app.text_synMarker.Tag = 'text_synMarker';
            app.text_synMarker.Text = 'Marker';
            app.popupmenu_synMarkerColor = uidropdown(app.SynapsesTab);
            app.popupmenu_synMarkerColor.Items = {'rand', 'rainbow', 'red', 'green', 'blue', 'yellow', 'magenta', 'cyan', 'white', 'grey'};
            app.popupmenu_synMarkerColor.ItemsData = [1 2 3 4 5 6 7 8 9 10];
            app.popupmenu_synMarkerColor.ValueChangedFcn = createCallbackFcn(app, @popupmenu_synMarkerColor_Callback, true);
            app.popupmenu_synMarkerColor.Tag = 'popupmenu_synMarkerColor';
            app.popupmenu_synMarkerColor.Value = 3;
            app.popupmenu_synMarkerAlpha = uidropdown(app.SynapsesTab);
            app.popupmenu_synMarkerAlpha.Items = {'0', '.1', '.2', '.3', '.4', '.5', '.5', '.6', '.7', '.8', '.9', '1'};
            app.popupmenu_synMarkerAlpha.ItemsData = [1 2 3 4 5 6 7 8 9 10 11];
            app.popupmenu_synMarkerAlpha.ValueChangedFcn = createCallbackFcn(app, @popupmenu_synMarkerAlpha_Callback, true);
            app.popupmenu_synMarkerAlpha.Tag = 'popupmenu_synMarkerAlpha';
            app.popupmenu_synMarkerAlpha.Value = 11;
            app.pushbutton_synMakeGroup = uibutton(app.SynapsesTab, 'push');
            app.pushbutton_synMakeGroup.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_synMakeGroup_Callback, true);
            app.pushbutton_synMakeGroup.Tag = 'pushbutton_synMakeGroup';
            app.pushbutton_synMakeGroup.Text = 'Create';
            app.popupmenu_synGroup = uidropdown(app.SynapsesTab);
            app.popupmenu_synGroup.Items = {'starter'};
            app.popupmenu_synGroup.ItemsData = 1;
            app.popupmenu_synGroup.ValueChangedFcn = createCallbackFcn(app, @popupmenu_synGroup_Callback, true);
            app.popupmenu_synGroup.Tag = 'popupmenu_synGroup';
            app.popupmenu_synGroup.Value = 1;
            app.pushbutton_synDelete = uibutton(app.SynapsesTab, 'push');
            app.pushbutton_synDelete.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_synDelete_Callback, true);
            app.pushbutton_synDelete.Tag = 'pushbutton_synDelete';
            app.pushbutton_synDelete.Text = 'Delete';
            app.pushbutton24 = uibutton(app.SynapsesTab, 'push');
            app.pushbutton24.ButtonPushedFcn = createCallbackFcn(app, @pushbutton24_Callback, true);
            app.pushbutton24.Tag = 'pushbutton24';
            app.pushbutton24.Text = '(-)';
            app.pushbutton25 = uibutton(app.SynapsesTab, 'push');
            app.pushbutton25.ButtonPushedFcn = createCallbackFcn(app, @pushbutton25_Callback, true);
            app.pushbutton25.Tag = 'pushbutton25';
            app.pushbutton25.Text = '(+)';
            app.popupmenu_synPreGroup = uidropdown(app.SynapsesTab);
            app.popupmenu_synPreGroup.Items = {'none'};
            app.popupmenu_synPreGroup.ItemsData = 1;
            app.popupmenu_synPreGroup.ValueChangedFcn = createCallbackFcn(app, @popupmenu_synPreGroup_Callback, true);
            app.popupmenu_synPreGroup.Tag = 'popupmenu_synPreGroup';
            app.popupmenu_synPreGroup.Value = 1;
            app.popupmenu_synPostGroup = uidropdown(app.SynapsesTab);
            app.popupmenu_synPostGroup.Items = {'none'};
            app.popupmenu_synPostGroup.ItemsData = 1;
            app.popupmenu_synPostGroup.ValueChangedFcn = createCallbackFcn(app, @popupmenu_synPostGroup_Callback, true);
            app.popupmenu_synPostGroup.Tag = 'popupmenu_synPostGroup';
            app.popupmenu_synPostGroup.Value = 1;
            app.edit_synPreGroup = uieditfield(app.SynapsesTab, 'text');
            app.edit_synPreGroup.Tag = 'edit_synPreGroup';
            app.edit_synPostGroup = uieditfield(app.SynapsesTab, 'text');
            app.edit_synPostGroup.Tag = 'edit_synPostGroup';
            app.togglebutton_synVisible = uibutton(app.SynapsesTab, 'state');
            app.togglebutton_synVisible.ValueChangedFcn = createCallbackFcn(app, @togglebutton_synVisible_Callback, true);
            app.togglebutton_synVisible.Tag = 'togglebutton_synVisible';
            app.togglebutton_synVisible.Text = 'Visible';
            app.togglebutton_synVisible.Value = true;
            app.popupmenu_preClassSynapse = uidropdown(app.SynapsesTab);
            app.popupmenu_preClassSynapse.Items = {'all', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.popupmenu_preClassSynapse.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.popupmenu_preClassSynapse.ValueChangedFcn = createCallbackFcn(app, @popPreClassSynapse, true);
            app.popupmenu_preClassSynapse.Tag = 'popupmenu_synSynapseType';
            app.popupmenu_preClassSynapse.Value = '1';
            app.text_synSynapseType_2 = uilabel(app.SynapsesTab);
            app.text_synSynapseType_2.Tag = 'text_synSynapseType';
            app.text_synSynapseType_2.Text = 'preClass';
            app.popupmenu_postClassSynapse = uidropdown(app.SynapsesTab);
            app.popupmenu_postClassSynapse.Items = {'all', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.popupmenu_postClassSynapse.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.popupmenu_postClassSynapse.ValueChangedFcn = createCallbackFcn(app, @popPostClassSynapse, true);
            app.popupmenu_postClassSynapse.Tag = 'popupmenu_synSynapseType';
            app.popupmenu_postClassSynapse.Value = '1';
            app.text_synSynapseType_3 = uilabel(app.SynapsesTab);
            app.text_synSynapseType_3.Tag = 'text_synSynapseType';
            app.text_synSynapseType_3.Text = 'postClass';
            app.LabelsTab = uitab(app.TabGroup);
            app.LabelsTab.Title = 'Labels';
            app.XSliderLabel = uilabel(app.LabelsTab);
            app.XSliderLabel.Text = 'X';
            app.scaleBar_XSlider = uislider(app.LabelsTab);
            app.scaleBar_XSlider.Limits = [0 500];
            app.scaleBar_XSlider.MajorTicks = [];
            app.scaleBar_XSlider.ValueChangedFcn = createCallbackFcn(app, @scaleBarSlide, true);
            app.scaleBar_XSlider.ValueChangingFcn = createCallbackFcn(app, @scaleBarSlide, true);
            app.scaleBar_XSlider.MinorTicks = [];
            app.scaleBar_XSlider.Value = 50;
            app.ZSliderLabel = uilabel(app.LabelsTab);
            app.ZSliderLabel.Text = 'Z';
            app.scaleBar_ZSlider = uislider(app.LabelsTab);
            app.scaleBar_ZSlider.Limits = [0 500];
            app.scaleBar_ZSlider.MajorTicks = [];
            app.scaleBar_ZSlider.ValueChangedFcn = createCallbackFcn(app, @scaleBarSlide, true);
            app.scaleBar_ZSlider.ValueChangingFcn = createCallbackFcn(app, @scaleBarSlide, true);
            app.scaleBar_ZSlider.MinorTicks = [];
            app.scaleBar_ZSlider.Value = 50;
            app.YSliderLabel = uilabel(app.LabelsTab);
            app.YSliderLabel.Text = 'Y';
            app.scaleBar_YSlider = uislider(app.LabelsTab);
            app.scaleBar_YSlider.Limits = [0 500];
            app.scaleBar_YSlider.MajorTicks = [];
            app.scaleBar_YSlider.ValueChangedFcn = createCallbackFcn(app, @scaleBarSlide, true);
            app.scaleBar_YSlider.ValueChangingFcn = createCallbackFcn(app, @scaleBarSlide, true);
            app.scaleBar_YSlider.MinorTicks = [];
            app.scaleBar_YSlider.Value = 50;
            app.scaleBar_ScaleBarButton = uibutton(app.LabelsTab, 'state');
            app.scaleBar_ScaleBarButton.ValueChangedFcn = createCallbackFcn(app, @toggle_scaleBar, true);
            app.scaleBar_ScaleBarButton.Text = 'Scale Bar';
            app.HeightEditFieldLabel = uilabel(app.LabelsTab);
            app.HeightEditFieldLabel.Text = 'Height';
            app.scaleBar_HeightEditField = uieditfield(app.LabelsTab, 'numeric');
            app.scaleBar_HeightEditField.ValueChangedFcn = createCallbackFcn(app, @scaleBarSlide, true);
            app.scaleBar_HeightEditField.Value = 1;
            app.WidthLabel = uilabel(app.LabelsTab);
            app.WidthLabel.Text = 'Width';
            app.scaleBar_WidthEditField = uieditfield(app.LabelsTab, 'numeric');
            app.scaleBar_WidthEditField.ValueChangedFcn = createCallbackFcn(app, @scaleBarSlide, true);
            app.scaleBar_WidthEditField.Value = 10;
            app.DimDropDownLabel = uilabel(app.LabelsTab);
            app.DimDropDownLabel.Text = 'Dim';
            app.scaleBar_DimDropDown = uidropdown(app.LabelsTab);
            app.scaleBar_DimDropDown.Items = {'Y', 'X', 'Z'};
            app.scaleBar_DimDropDown.DropDownOpeningFcn = createCallbackFcn(app, @scaleBarSlide, true);
            app.scaleBar_DimDropDown.ValueChangedFcn = createCallbackFcn(app, @scaleBarSlide, true);
            app.scaleBar_DimDropDown.Value = 'Y';
            app.ClickPosButton = uibutton(app.LabelsTab, 'state');
            app.ClickPosButton.ValueChangedFcn = createCallbackFcn(app, @scaleBar_clickPos, true);
            app.ClickPosButton.Text = 'Click Pos';
            app.RegistertoViewAngleButton = uibutton(app.LabelsTab, 'push');
            app.RegistertoViewAngleButton.ButtonPushedFcn = createCallbackFcn(app, @scaleBar_registerAngle, true);
            app.RegistertoViewAngleButton.Text = 'Register to View Angle';
            app.SavePicTab = uitab(app.TabGroup);
            app.SavePicTab.Title = 'Save Pic';
            app.edit13 = uieditfield(app.SavePicTab, 'text');
            app.edit13.ValueChangedFcn = createCallbackFcn(app, @edit13_Callback, true);
            app.edit13.Tag = 'edit13';
            app.edit13.Value = 'cellNavSave';
            app.pushbutton_SelectDir = uibutton(app.SavePicTab, 'push');
            app.pushbutton_SelectDir.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_SelectDir_Callback, true);
            app.pushbutton_SelectDir.Tag = 'pushbutton_SelectDir';
            app.pushbutton_SelectDir.Text = 'Choose Directory';
            app.edit_saveDirectory = uieditfield(app.SavePicTab, 'text');
            app.edit_saveDirectory.Tag = 'edit_saveDirectory';
            app.edit_saveDirectory.Value = 'Z:\Active\morganLab\karlsRetina\Analysis\cellNav\Karl_210121\DF2020\saves\';
            app.text_FileName = uilabel(app.SavePicTab);
            app.text_FileName.Tag = 'text_FileName';
            app.text_FileName.Text = 'File Name';
            app.pushbutton_snapShot = uibutton(app.SavePicTab, 'push');
            app.pushbutton_snapShot.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_snapShot_Callback, true);
            app.pushbutton_snapShot.Tag = 'pushbutton_snapShot';
            app.pushbutton_snapShot.Text = 'Snap Shot';
            app.popupmenu_renderFunctions = uidropdown(app.SavePicTab);
            app.popupmenu_renderFunctions.Items = {'orbitCam'};
            app.popupmenu_renderFunctions.ItemsData = 1;
            app.popupmenu_renderFunctions.Tag = 'popupmenu_renderFunctions';
            app.popupmenu_renderFunctions.Value = 1;
            app.pushbutton_runRender = uibutton(app.SavePicTab, 'push');
            app.pushbutton_runRender.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_runRender_Callback, true);
            app.pushbutton_runRender.Tag = 'pushbutton_runRender';
            app.pushbutton_runRender.Text = 'Run';
            app.pushbutton_saveGlob = uibutton(app.SavePicTab, 'push');
            app.pushbutton_saveGlob.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_saveGlob_Callback, true);
            app.pushbutton_saveGlob.Tag = 'pushbutton_saveGlob';
            app.pushbutton_saveGlob.Text = 'Save Figure';
            app.pushbutton_loadGlob = uibutton(app.SavePicTab, 'push');
            app.pushbutton_loadGlob.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_loadGlob_Callback, true);
            app.pushbutton_loadGlob.Tag = 'pushbutton_loadGlob';
            app.pushbutton_loadGlob.Text = 'Load';
            app.InvertPalletCheckBox = uicheckbox(app.SavePicTab);
            app.InvertPalletCheckBox.Text = 'Invert Pallet';
            app.CropEmptyEdgesCheckBox = uicheckbox(app.SavePicTab);
            app.CropEmptyEdgesCheckBox.Text = 'Crop Empty Edges';
            app.PNGfastsmallCheckBox = uicheckbox(app.SavePicTab);
            app.PNGfastsmallCheckBox.Text = 'PNG (fast, small)';
            app.PNGfastsmallCheckBox.Value = true;
            app.PDFvectorCheckBox = uicheckbox(app.SavePicTab);
            app.PDFvectorCheckBox.Text = 'PDF (vector)';
            app.DrawpathTab = uitab(app.TabGroup);
            app.DrawpathTab.Title = 'Draw path';
            app.text_pathPanel_points = uilabel(app.DrawpathTab);
            app.text_pathPanel_points.Tag = 'text_pathPanel_points';
            app.text_pathPanel_points.Text = 'Points';
            app.edit_pathPanelPoints = uitextarea(app.DrawpathTab);
            app.edit_pathPanelPoints.Tag = 'edit_pathPanelPoints';
            app.text_pathPanelLengths = uilabel(app.DrawpathTab);
            app.text_pathPanelLengths.Tag = 'text_pathPanelLengths';
            app.text_pathPanelLengths.Text = 'Lengths';
            app.edit_pathPanelLengths = uitextarea(app.DrawpathTab);
            app.edit_pathPanelLengths.Tag = 'edit_pathPanelLengths';
            app.text_pathPanelTotLength = uilabel(app.DrawpathTab);
            app.text_pathPanelTotLength.Tag = 'text_pathPanelTotLength';
            app.text_pathPanelTotLength.Text = 'Total Length';
            app.edit5 = uieditfield(app.DrawpathTab, 'text');
            app.edit5.Tag = 'edit5';
            app.edit5.Value = 'Edit Text';
            app.text_pathPanelXYArea = uilabel(app.DrawpathTab);
            app.text_pathPanelXYArea.Tag = 'text_pathPanelXYArea';
            app.text_pathPanelXYArea.Text = 'Total XY Area';
            app.edit_pathPanelXYArea = uieditfield(app.DrawpathTab, 'text');
            app.edit_pathPanelXYArea.Tag = 'edit_pathPanelXYArea';
            app.edit_pathPanelXYArea.Value = 'Edit Text';
            app.text_pathPanelVolume = uilabel(app.DrawpathTab);
            app.text_pathPanelVolume.Tag = 'text_pathPanelVolume';
            app.text_pathPanelVolume.Text = 'Convex Volume';
            app.edit_pathPanelVolume = uieditfield(app.DrawpathTab, 'text');
            app.edit_pathPanelVolume.Tag = 'edit_pathPanelVolume';
            app.edit_pathPanelVolume.Value = 'Edit Text';
            app.text_ConvexHullArea = uilabel(app.DrawpathTab);
            app.text_ConvexHullArea.Tag = 'text_ConvexHullArea';
            app.text_ConvexHullArea.Text = 'Convex Area';
            app.edit_pathPanel_ConvexHullArea = uieditfield(app.DrawpathTab, 'text');
            app.edit_pathPanel_ConvexHullArea.Tag = 'edit_pathPanel_ConvexHullArea';
            app.togglebutton_measurePath = uibutton(app.DrawpathTab, 'state');
            app.togglebutton_measurePath.ValueChangedFcn = createCallbackFcn(app, @togglebutton_measurePath_Callback, true);
            app.togglebutton_measurePath.Tag = 'togglebutton_measurePath';
            app.togglebutton_measurePath.Text = 'Draw';
            app.pushbutton_undoPath = uibutton(app.DrawpathTab, 'push');
            app.pushbutton_undoPath.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_undoPath_Callback, true);
            app.pushbutton_undoPath.Tag = 'pushbutton_undoPath';
            app.pushbutton_undoPath.Text = '(-)';
            app.pushbutton_clearPath = uibutton(app.DrawpathTab, 'push');
            app.pushbutton_clearPath.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_clearPath_Callback, true);
            app.pushbutton_clearPath.Tag = 'pushbutton_clearPath';
            app.pushbutton_clearPath.Text = 'Clear';
            app.togglebutton_hidePath = uibutton(app.DrawpathTab, 'state');
            app.togglebutton_hidePath.ValueChangedFcn = createCallbackFcn(app, @togglebutton_hidePath_Callback, true);
            app.togglebutton_hidePath.Tag = 'togglebutton_hidePath';
            app.togglebutton_hidePath.Text = 'Hide';
            app.EditpatchTab = uitab(app.TabGroup);
            app.EditpatchTab.Title = 'Edit patch';
            app.edit_color = uieditfield(app.EditpatchTab, 'text');
            app.edit_color.ValueChangedFcn = createCallbackFcn(app, @edit_color_Callback, true);
            app.edit_color.Tag = 'edit_color';
            app.text_color = uilabel(app.EditpatchTab);
            app.text_color.Tag = 'text_color';
            app.text_color.Text = 'RGB [1 1 1]';
            app.edit_alpha = uieditfield(app.EditpatchTab, 'text');
            app.edit_alpha.ValueChangedFcn = createCallbackFcn(app, @edit_alpha_Callback, true);
            app.edit_alpha.Tag = 'edit_alpha';
            app.text_alpha = uilabel(app.EditpatchTab);
            app.text_alpha.Tag = 'text_alpha';
            app.text_alpha.Text = 'Alpha(0-1)';
            app.togglebutton_PaintPatch = uibutton(app.EditpatchTab, 'state');
            app.togglebutton_PaintPatch.ValueChangedFcn = createCallbackFcn(app, @togglebutton_PaintPatch_Callback, true);
            app.togglebutton_PaintPatch.Tag = 'togglebutton_PaintPatch';
            app.togglebutton_PaintPatch.Text = 'Paint Patch';
            app.togglebutton_SelectPatch = uibutton(app.EditpatchTab, 'state');
            app.togglebutton_SelectPatch.ValueChangedFcn = createCallbackFcn(app, @togglebutton_SelectPatch_Callback, true);
            app.togglebutton_SelectPatch.Tag = 'togglebutton_SelectPatch';
            app.togglebutton_SelectPatch.Text = 'Select Patch';
            app.ConsoleTab = uitab(app.TabGroup);
            app.ConsoleTab.Title = 'Console';
            app.popupmenu_funcTypes = uidropdown(app.ConsoleTab);
            app.popupmenu_funcTypes.Items = {'all files', 'all registered', 'morphology', 'connectivity', 'path'};
            app.popupmenu_funcTypes.ItemsData = [1 2 3 4 5];
            app.popupmenu_funcTypes.ValueChangedFcn = createCallbackFcn(app, @popupmenu_funcTypes_Callback, true);
            app.popupmenu_funcTypes.Tag = 'popupmenu_funcTypes';
            app.popupmenu_funcTypes.Value = 1;
            app.popupmenu_functions = uidropdown(app.ConsoleTab);
            app.popupmenu_functions.Items = {'getDirs', 'loadFV', 'makeFuncROI', 'makeROI', 'orbitCam', 'piePoints', 'roi2synProfile', 'runFindAppositions'};
            app.popupmenu_functions.ItemsData = [1 2 3 4 5 6 7 8 9];
            app.popupmenu_functions.Tag = 'popupmenu_functions';
            app.popupmenu_functions.Value = 1;
            app.text_functionType = uilabel(app.ConsoleTab);
            app.text_functionType.Tag = 'text_functionType';
            app.text_functionType.Text = 'Type';
            app.pushbutton_Function = uibutton(app.ConsoleTab, 'push');
            app.pushbutton_Function.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Function_Callback, true);
            app.pushbutton_Function.Tag = 'pushbutton_Function';
            app.pushbutton_Function.Text = 'Function';
            app.edit_eval = uitextarea(app.ConsoleTab);
            app.edit_eval.ValueChangedFcn = createCallbackFcn(app, @edit_eval_Callback, true);
            app.edit_eval.Tag = 'edit_eval';
            app.pushbutton_eval = uibutton(app.ConsoleTab, 'push');
            app.pushbutton_eval.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_eval_Callback, true);
            app.pushbutton_eval.Tag = 'pushbutton_eval';
            app.pushbutton_eval.Text = 'Evaluate';
            app.edit_result = uitextarea(app.ConsoleTab);
            app.edit_result.Tag = 'edit_result';
            app.HistogramsTab = uitab(app.TabGroup);
            app.HistogramsTab.Title = 'Histograms';
            app.axesDepth = uiaxes(app.HistogramsTab);
            app.axesDepth.Tag = 'axesDepth';
            app.toggle_CellData = uibutton(app.HistogramsTab, 'state');
            app.toggle_CellData.ValueChangedFcn = createCallbackFcn(app, @toggle_CellData_Callback, true);
            app.toggle_CellData.Tag = 'toggle_CellData';
            app.toggle_CellData.Text = 'Cell Dat';
            app.toggle_groupData1 = uibutton(app.HistogramsTab, 'state');
            app.toggle_groupData1.ValueChangedFcn = createCallbackFcn(app, @toggle_groupData1_Callback, true);
            app.toggle_groupData1.Tag = 'toggle_groupData1';
            app.toggle_groupData1.Text = 'Group 1';
            app.toggle_groupData2 = uibutton(app.HistogramsTab, 'state');
            app.toggle_groupData2.ValueChangedFcn = createCallbackFcn(app, @toggle_groupData2_Callback, true);
            app.toggle_groupData2.Tag = 'toggle_groupData2';
            app.toggle_groupData2.Text = 'Group 2';
            app.toggle_refDat = uibutton(app.HistogramsTab, 'state');
            app.toggle_refDat.ValueChangedFcn = createCallbackFcn(app, @toggle_refDat_Callback, true);
            app.toggle_refDat.Tag = 'toggle_refDat';
            app.toggle_refDat.Text = 'Ref Dat';
            app.CloseButton = uibutton(app.figure1, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @closeCellNaveFig, true);
            app.CloseButton.Text = 'Close';
            app.ClearPanel = uipanel(app.figure1);
            app.ClearPanel.Title = 'Clear';
            app.AllButton = uibutton(app.ClearPanel, 'push');
            app.AllButton.ButtonPushedFcn = createCallbackFcn(app, @clearAll_button, true);
            app.AllButton.Text = 'All';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cellNavGuide_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)cellNavGuide_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end